classdef SimonSays < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_20                       matlab.ui.control.Label
        Label_19                       matlab.ui.control.Label
        Label_18                       matlab.ui.control.Label
        Label_16                       matlab.ui.control.Label
        Label_15                       matlab.ui.control.Label
        Label_14                       matlab.ui.control.Label
        Label_13                       matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SIMONSAYSLabel                 matlab.ui.control.Label
        CheckSequenceButton            matlab.ui.control.Button
        RepeatSequenceButton           matlab.ui.control.Button
        NextRoundButton                matlab.ui.control.Button
        Button_12                      matlab.ui.control.Button
        Button_11                      matlab.ui.control.Button
        Button_10                      matlab.ui.control.Button
        Button_9                       matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        GameStatusEditField            matlab.ui.control.EditField
        GameStatusEditFieldLabel       matlab.ui.control.Label
        RoundNumberEditField           matlab.ui.control.NumericEditField
        RoundNumberEditFieldLabel      matlab.ui.control.Label
        QuitGameButton                 matlab.ui.control.Button
        AttemptsRemainingSpinner       matlab.ui.control.Spinner
        AttemptsRemainingSpinnerLabel  matlab.ui.control.Label
        NumberofRoundsSpinner          matlab.ui.control.Spinner
        NumberofRoundsSpinnerLabel     matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        PasswordEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        UsernameEditField              matlab.ui.control.EditField
        UsernameEditFieldLabel         matlab.ui.control.Label
        IbadSIddiqui2053648FritzClaydonLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        PressedBoard = zeros(3,4);
        RandomSequence = randperm(12);
        RandomSequence_Index = 1;
        TotalRounds = 0;
        a = 0;
        CheckBoard = zeros(3,4);
        Pins = ["D13", "D12", "D11";
                "D10", "D9", "D8";
                "D7", "D6", "D5";
                "D4", "D3", "D2"];
        Board = zeros(4,3);
        BoardP = zeros(3,4);
        RoundNumber = 0;
        AttemptsRemaining = 0;
        File1 = 0;
        fs1 = 0;
        File2 = 0;
        fs2 = 0;
        File3 = 0;
        fs3 = 0;
        File4 = 0;
        fs4 = 0;
        File5 = 0;
        fs5 = 0;
        File6 = 0;
        fs6 = 0;
        File7 = 0;
        fs7 = 0;
        File8 = 0;
        fs8 = 0;
        File9 = 0;
        fs9 = 0;
        File10 = 0;
        fs10 = 0;
        File11 = 0;
        fs11 = 0;
        File12 = 0;
        fs12 = 0;
        playerObj1 = 0;
        playerObj2 = 0;
        playerObj3 = 0;
        playerObj4 = 0;
        playerObj5 = 0;
        playerObj6 = 0;
        playerObj7 = 0;
        playerObj8 = 0;
        playerObj9 = 0;
        playerObj10 = 0;
        playerObj11 = 0;
        playerObj12 = 0;
        Sounds = [];
        TransposedSounds = [];
        ButtonClicked = zeros(4,3);
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Conditions to start the game
            if app.UsernameEditField.Value ~= "isiddiq5" || app.PasswordEditField.Value ~= "password"
                error("Enter the right username / password to begin playing.")
            end
            if app.AttemptsRemainingSpinner.Value == 0
                error("You need to have attempts to start the game!")
            end
            
            % Enabling the Buttons once the Game is started
            app.a = arduino();
            app.Button.Enable = "on";
            app.Button_2.Enable = "on";
            app.Button_3.Enable = "on";
            app.Button_4.Enable = "on";
            app.Button_5.Enable = "on";
            app.Button_6.Enable = "on";
            app.Button_7.Enable = "on";
            app.Button_8.Enable = "on";
            app.Button_9.Enable = "on";
            app.Button_10.Enable = "on";
            app.Button_11.Enable = "on";
            app.Button_12.Enable = "on";
            app.CheckSequenceButton.Enable = "on";
            app.GameStatusEditField.Enable = "on";
            app.QuitGameButton.Enable = "on";
            app.RoundNumberEditField.Enable = "on";
            app.AttemptsRemainingSpinner.Editable = "off";
            app.NumberofRoundsSpinner.Editable = "off";
            
            app.GameStatusEditField.Value = "Let's Begin!";
            
            % Initial Variables
            app.TotalRounds = app.NumberofRoundsSpinner.Value;
            app.AttemptsRemaining = app.AttemptsRemainingSpinner.Value;
            app.RoundNumberEditField.Value = 1;
            app.RoundNumber = app.RoundNumberEditField.Value;
            
            % Sound Files
            [app.File1,app.fs1] = audioread("1.wav");
            [app.File2,app.fs2] = audioread("2.wav");
            [app.File3,app.fs3] = audioread("3.wav");
            [app.File4,app.fs4] = audioread("4.wav");
            [app.File5,app.fs5] = audioread("5.wav");
            [app.File6,app.fs6] = audioread("6.wav");
            [app.File7,app.fs7] = audioread("7.wav");
            [app.File8,app.fs8] = audioread("8.wav");
            [app.File9,app.fs9] = audioread("9.wav");
            [app.File10,app.fs10] = audioread("10.wav");
            [app.File11,app.fs11] = audioread("11.wav");
            [app.File12,app.fs12] = audioread("12.wav");
            app.playerObj1 = audioplayer(app.File1,app.fs1);
            app.playerObj2 = audioplayer(app.File2,app.fs2);
            app.playerObj3 = audioplayer(app.File3,app.fs3);
            app.playerObj4 = audioplayer(app.File4,app.fs4);
            app.playerObj5 = audioplayer(app.File5,app.fs5);
            app.playerObj6 = audioplayer(app.File6,app.fs6);
            app.playerObj7 = audioplayer(app.File7,app.fs7);
            app.playerObj8 = audioplayer(app.File8,app.fs8);
            app.playerObj9 = audioplayer(app.File9,app.fs9);
            app.playerObj10 = audioplayer(app.File10,app.fs10);
            app.playerObj11 = audioplayer(app.File11,app.fs11);
            app.playerObj12 = audioplayer(app.File12,app.fs12);
            app.Sounds = {app.playerObj12,app.playerObj11,app.playerObj10;
                app.playerObj9,app.playerObj8,app.playerObj7;
                app.playerObj6,app.playerObj5,app.playerObj4;
                app.playerObj3,app.playerObj2,app.playerObj1};
            app.TransposedSounds = transpose(app.Sounds);
            
            app.BoardP(abs(app.RandomSequence(app.RandomSequence_Index)-12)+1) = 1;   % Turning on the proper lights
            UpdateBoard(app.a,app.BoardP,transpose(app.Pins))
            play(app.TransposedSounds{abs(app.RandomSequence(app.RandomSequence_Index)-12)+1})   % Playing Sound
            pause(2)
            app.Board = zeros(4,3);
            UpdateBoard(app.a,app.Board,app.Pins)
            app.CheckBoard(abs(app.RandomSequence(app.RandomSequence_Index)-12)+1) = app.CheckBoard(abs(app.RandomSequence(app.RandomSequence_Index)-12)+1) + 1;
              
            app.StartButton.Enable = "off";
        end

        % Button pushed function: Button_2
        function Two(app, event)
            app.PressedBoard(2,1) = 1;
            app.ButtonClicked(1,2) = 1;
            play(app.Sounds{1,2})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button
        function One(app, event)
            app.PressedBoard(1,1) = 1;
            app.ButtonClicked(1,1) = 1;
            play(app.Sounds{1,1})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_3
        function Three(app, event)
            app.PressedBoard(3,1) = 1;
            app.ButtonClicked(1,3) = 1;
            play(app.Sounds{1,3})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_4
        function Four(app, event)
            app.PressedBoard(1,2) = 1;
            app.ButtonClicked(2,1) = 1;
            play(app.Sounds{2,1})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_5
        function Five(app, event)
            app.PressedBoard(2,2) = 1;
            app.ButtonClicked(2,2) = 1;
            play(app.Sounds{2,2})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_6
        function Six(app, event)
            app.PressedBoard(3,2) = 1;
            app.ButtonClicked(2,3) = 1;
            play(app.Sounds{2,3})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_7
        function Seven(app, event)
            app.PressedBoard(1,3) = 1;
            app.ButtonClicked(3,1) = 1;
            play(app.Sounds{3,1})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_8
        function Eight(app, event)
            app.PressedBoard(2,3) = 1;
            app.ButtonClicked(3,2) = 1;
            play(app.Sounds{3,2})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_9
        function Nine(app, event)
            app.PressedBoard(3,3) = 1;
            app.ButtonClicked(3,3) = 1;
            play(app.Sounds{3,3})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_10
        function Ten(app, event)
            app.PressedBoard(1,4) = 1;
            app.ButtonClicked(4,1) = 1;
            play(app.Sounds{4,1})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_12
        function Eleven(app, event)
            app.PressedBoard(2,4) = 1;
            app.ButtonClicked(4,2) = 1;
            play(app.Sounds{4,2})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: Button_11
        function Twelve(app, event)
            app.PressedBoard(3,4) = 1;
            app.ButtonClicked(4,3) = 1;
            play(app.Sounds{4,3})
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
            app.ButtonClicked = zeros(4,3);
            pause(2)
            UpdateBoard(app.a,app.ButtonClicked,app.Pins)
        end

        % Button pushed function: CheckSequenceButton
        function CheckSequenceButtonPushed(app, event)
%             matrix = app.PressedBoard
%             answer = app.CheckBoard
            if app.PressedBoard == app.CheckBoard
                app.NextRoundButton.Enable = "on";
                app.GameStatusEditField.Value = "Correct Sequence, on to the next round!";
                % Win Conditions
                if app.RandomSequence_Index == app.TotalRounds
                    app.GameStatusEditField.Value = "You beat the game!";
                    app.CheckSequenceButton.Enable = "off";
                    app.NextRoundButton.Enable = "off";  
                    app.Board = zeros(4,3);
                    UpdateBoard(app.a,app.Board,app.Pins)
                    app.BoardP(1,:) = 1;
                    app.BoardP(2,end-1) = 1;
                    app.BoardP(3,:) = 1;
                        % The Pattern that is displayed for a loss is 
                        % a "W" in which all the lights in the first and
                        % third columns turn on and the second last light
                        % in the second column turns on.
                    play(app.Sounds{1,3})
                    play(app.Sounds{1,2})
                    pause(1)
                        % The sound for a victory is two high notes played in
                        % succession
                    play(app.Sounds{1,2})
                    play(app.Sounds{1})
                    pause(1)
                    play(app.Sounds{1})
                    UpdateBoard(app.a,app.BoardP,transpose(app.Pins))
                    pause(5)
                end
            else
                app.RepeatSequenceButton.Enable = "on";
                app.AttemptsRemainingSpinner.Value = app.AttemptsRemainingSpinner.Value - 1;
                app.GameStatusEditField.Value = "Incorrect Sequence, you lost a life!";
                % Losing Conditions
                if app.AttemptsRemainingSpinner.Value == 0
                    app.GameStatusEditField.Value = "You lost the game!";
                    app.CheckSequenceButton.Enable = "off";
                    app.NextRoundButton.Enable = "off"; 
                    app.RepeatSequenceButton.Enable = "off";
                    app.Board = zeros(4,3);
                    UpdateBoard(app.a,app.Board,app.Pins)                    
                    app.BoardP(1,:) = 1;
                    app.BoardP(2,end) = 1; 
                    app.BoardP(3,end) = 1;
                        % The Pattern that is displayed for a loss 
                        % is an "L" in which all the lights in the first column turn on 
                        % and the bottom row lights turn on.
                    play(app.Sounds{end,end-2})
                    play(app.Sounds{end,end-1})
                    pause(1) 
                        % The sound for the loss is two low notes played in
                        % succession
                    play(app.Sounds{end,end-1})                    
                    play(app.Sounds{end})
                    pause(1)
                    play(app.Sounds{end})
                    UpdateBoard(app.a,app.BoardP,transpose(app.Pins))
                    pause(5)
                end
            end
        end

        % Button pushed function: RepeatSequenceButton
        function RepeatSequenceButtonPushed(app, event)
            app.RepeatSequenceButton.Enable = "off"; 
            app.BoardP = zeros(3,4);
            app.CheckBoard = zeros(3,4);
            app.PressedBoard = zeros(3,4);
            
            if app.RandomSequence_Index <= app.RoundNumber
               for i = 1:app.RandomSequence_Index
                   app.CheckBoard(abs(app.RandomSequence(i)-12)+1) = app.CheckBoard(abs(app.RandomSequence(i)-12)+1) + 1;
                   app.BoardP(abs(app.RandomSequence(i)-12)+1) = 1;
                   play(app.TransposedSounds{abs(app.RandomSequence(i)-12)+1})
                   UpdateBoard(app.a,app.BoardP,transpose(app.Pins))
                   pause(2)
                   app.BoardP = zeros(3,4);
                   UpdateBoard(app.a,app.BoardP,transpose(app.Pins))
                   pause(2)
               end
            end
        end

        % Button pushed function: NextRoundButton
        function NextRoundButtonPushed(app, event)
%            a = arduino(); 
            app.NextRoundButton.Enable = "off";
            % Increasing the values to represent the next round
            app.RoundNumberEditField.Value = app.RoundNumberEditField.Value + 1;
            app.RandomSequence_Index = app.RandomSequence_Index + 1;
            app.RoundNumber = app.RoundNumberEditField.Value + 1;
            
            % Zeros in the matrices so that the times clicked does not add
            % up
            
            app.BoardP = zeros(3,4);
            app.CheckBoard = zeros(3,4);
            app.PressedBoard = zeros(3,4);
           
            if app.RandomSequence_Index < app.RoundNumber
               for i = 1:app.RandomSequence_Index
                   app.CheckBoard(abs(app.RandomSequence(i)-12)+1) = app.CheckBoard(abs(app.RandomSequence(i)-12)+1) + 1;
                   app.BoardP(abs(app.RandomSequence(i)-12)+1) = 1;
                   play(app.TransposedSounds{abs(app.RandomSequence(i)-12)+1})                   
                   UpdateBoard(app.a,app.BoardP,transpose(app.Pins))
                   pause(2)
                   app.BoardP = zeros(3,4);
                   UpdateBoard(app.a,app.BoardP,transpose(app.Pins))
                   pause(2)
               end
            end
        end

        % Button pushed function: QuitGameButton
        function QuitGameButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            SimonSays();
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IbadSIddiqui2053648FritzClaydonLabel = uilabel(app.UIFigure);
            app.IbadSIddiqui2053648FritzClaydonLabel.Text = 'Ibad SIddiqui 2053648 Fritz Claydon';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NumberofRoundsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofRoundsSpinnerLabel.Text = 'Number of Rounds';
            app.NumberofRoundsSpinner = uispinner(app.UIFigure);
            app.NumberofRoundsSpinner.Limits = [3 12];
            app.NumberofRoundsSpinner.Value = 3;
            app.AttemptsRemainingSpinnerLabel = uilabel(app.UIFigure);
            app.AttemptsRemainingSpinnerLabel.Text = 'Attempts Remaining ';
            app.AttemptsRemainingSpinner = uispinner(app.UIFigure);
            app.AttemptsRemainingSpinner.Limits = [0 5];
            app.AttemptsRemainingSpinner.Value = 1;
            app.QuitGameButton = uibutton(app.UIFigure, 'push');
            app.QuitGameButton.ButtonPushedFcn = createCallbackFcn(app, @QuitGameButtonPushed, true);
            app.QuitGameButton.Enable = 'off';
            app.QuitGameButton.Text = 'Quit Game';
            app.RoundNumberEditFieldLabel = uilabel(app.UIFigure);
            app.RoundNumberEditFieldLabel.Text = 'Round Number';
            app.RoundNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoundNumberEditField.Enable = 'off';
            app.GameStatusEditFieldLabel = uilabel(app.UIFigure);
            app.GameStatusEditFieldLabel.Text = 'Game Status';
            app.GameStatusEditField = uieditfield(app.UIFigure, 'text');
            app.GameStatusEditField.Enable = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @One, true);
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Two, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Three, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Four, true);
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Five, true);
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Six, true);
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Seven, true);
            app.Button_7.Enable = 'off';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Eight, true);
            app.Button_8.Enable = 'off';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Nine, true);
            app.Button_9.Enable = 'off';
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Ten, true);
            app.Button_10.Enable = 'off';
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Twelve, true);
            app.Button_11.Enable = 'off';
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Eleven, true);
            app.Button_12.Enable = 'off';
            app.Button_12.Text = '';
            app.NextRoundButton = uibutton(app.UIFigure, 'push');
            app.NextRoundButton.ButtonPushedFcn = createCallbackFcn(app, @NextRoundButtonPushed, true);
            app.NextRoundButton.Enable = 'off';
            app.NextRoundButton.Text = 'Next Round';
            app.RepeatSequenceButton = uibutton(app.UIFigure, 'push');
            app.RepeatSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatSequenceButtonPushed, true);
            app.RepeatSequenceButton.Enable = 'off';
            app.RepeatSequenceButton.Text = 'Repeat Sequence';
            app.CheckSequenceButton = uibutton(app.UIFigure, 'push');
            app.CheckSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSequenceButtonPushed, true);
            app.CheckSequenceButton.Enable = 'off';
            app.CheckSequenceButton.Text = 'Check Sequence';
            app.SIMONSAYSLabel = uilabel(app.UIFigure);
            app.SIMONSAYSLabel.Text = 'S I M O N   S A Y S ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimonSays

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end