classdef isiddiq5_App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CalculatorTab                  matlab.ui.container.Tab
        FreezingPointforWaterLabel     matlab.ui.control.Label
        BoilingPointforWaterLamp       matlab.ui.control.Lamp
        BoilingPointforWaterLampLabel  matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        ChoosetheunitstoconvertfromandtoconverttoYoucanchoosetheLabel_2  matlab.ui.control.Label
        RLabel_10                      matlab.ui.control.Label
        ELabel_13                      matlab.ui.control.Label
        TLabel_8                       matlab.ui.control.Label
        RLabel_9                       matlab.ui.control.Label
        ELabel_12                      matlab.ui.control.Label
        VLabel_2                       matlab.ui.control.Label
        NLabel_2                       matlab.ui.control.Label
        OLabel_2                       matlab.ui.control.Label
        CLabel_2                       matlab.ui.control.Label
        ELabel_11                      matlab.ui.control.Label
        RLabel_8                       matlab.ui.control.Label
        ULabel_3                       matlab.ui.control.Label
        TLabel_7                       matlab.ui.control.Label
        ALabel_3                       matlab.ui.control.Label
        RLabel_7                       matlab.ui.control.Label
        PLabel_3                       matlab.ui.control.Label
        ELabel_10                      matlab.ui.control.Label
        MLabel_3                       matlab.ui.control.Label
        ELabel_9                       matlab.ui.control.Label
        TLabel_6                       matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ChangeinTemperatureCheckBox    matlab.ui.control.CheckBox
        ConvertedTemperatureEditField  matlab.ui.control.NumericEditField
        ConvertedTemperatureEditFieldLabel  matlab.ui.control.Label
        UnitsDropDown_2                matlab.ui.control.DropDown
        UnitsDropDown_2Label           matlab.ui.control.Label
        ConvertButton                  matlab.ui.control.Button
        InitialTemperatureEditField    matlab.ui.control.NumericEditField
        InitialTemperatureEditFieldLabel  matlab.ui.control.Label
        UnitsDropDown                  matlab.ui.control.DropDown
        UnitsDropDownLabel             matlab.ui.control.Label
        AppSummaryTab                  matlab.ui.container.Tab
        SummaryandFeaturesLabel        matlab.ui.control.Label
        IbadSiddiqui2053648DrKowalLabel_2  matlab.ui.control.Label
        ChoosetheunitstoconvertfromandtoconverttoYoucanchoosetheLabel_3  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            Initial_Temperature = app.InitialTemperatureEditField.Value;
            Converted_Temperature = app.ConvertedTemperatureEditField.Value;
            Units = app.UnitsDropDown.Value;
            Converted_Units = app.UnitsDropDown_2.Value;
            Temp1 = 0;
            Temp2 = 0;
            if app.ChangeinTemperatureCheckBox.Value == 0
            switch Units
                case 'F'
                    switch Converted_Units
                        case 'F'
                          error("There is no conversion taking place.")
                        case 'K'
                          Converted_Temperature = (Initial_Temperature - 32) * 5/9 + 273.15;
                        case 'R'
                          Converted_Temperature = Initial_Temperature + 459.67;
                        case 'C'
                          Converted_Temperature = (Initial_Temperature - 32) * 5/9; 
                    end
                    if Initial_Temperature == 32
                        for i = [0:5]
                            app.Lamp.Color = [1,0,0];
                            pause(0.5)
                            app.Lamp.Color = [0,0,0];
                            pause(0.5)
                        end
                    end
                    if Initial_Temperature == 212
                        for i = [0:5]
                            app.BoilingPointforWaterLamp.Color = [1,0,0];
                            pause(0.5)
                            app.BoilingPointforWaterLamp.Color = [0,0,0];
                            pause(0.5)
                        end                        
                    end
                    if Initial_Temperature <= -459.67
                        error("Enter a temperature above the absolute temperature!")
                    end
                case 'K'
                    switch Converted_Units
                        case 'F'
                            Converted_Temperature = (Initial_Temperature - 273.15) * 9/5 + 32;
                        case 'K'
                            error("There is no conversion taking place.")
                        case 'R'
                            Converted_Temperature = Initial_Temperature * 9/5;
                        case 'C'
                            Converted_Temperature = Initial_Temperature - 273.15;
                    end
                    if Initial_Temperature == 273
                        for i = [0:5]
                            app.Lamp.Color = [1,0,0];
                            pause(0.5)
                            app.Lamp.Color = [0,0,0];
                            pause(0.5)
                        end
                    end
                    if Initial_Temperature == 373
                        for i = [0:5]
                            app.BoilingPointforWaterLamp.Color = [1,0,0];
                            pause(0.5)
                            app.BoilingPointforWaterLamp.Color = [0,0,0];
                            pause(0.5)
                        end                        
                    end
                    if Initial_Temperature <= 0
                        error("Enter a temperature above the absolute temperature!")
                    end
                case 'R'
                    switch Converted_Units
                        case 'F'
                            Converted_Temperature = Initial_Temperature - 459.67;
                        case 'K'
                            Converted_Temperature = Initial_Temperature * 5/9;
                        case 'R'
                            error("There is no conversion taking place.")
                        case 'C'
                            Converted_Temperature = (Initial_Temperature - 491.67) * 5/9;
                    end
                    if Initial_Temperature == 492
                        for i = [0:5]
                            app.Lamp.Color = [1,0,0];
                            pause(0.5)
                            app.Lamp.Color = [0,0,0];
                            pause(0.5)
                        end
                    end
                    if Initial_Temperature == 672
                        for i = [0:5]
                            app.BoilingPointforWaterLamp.Color = [1,0,0];
                            pause(0.5)
                            app.BoilingPointforWaterLamp.Color = [0,0,0];
                            pause(0.5)
                        end                        
                    end
                    if Initial_Temperature <= 0
                        error("Enter a temperature above the absolute temperature!")
                    end
                case 'C'
                    switch Converted_Units
                        case 'F'
                            Converted_Temperature = Initial_Temperature * 9/5 + 32;
                        case 'K'
                            Converted_Temperature = Initial_Temperature + 273.15;
                        case 'R'
                            Converted_Temperature = Initial_Temperature * 9/5 + 491.67;
                        case 'C'
                            error("There is no conversion taking place.")
                    end
                    if Initial_Temperature == 0
                        for i = [0:5]
                            app.Lamp.Color = [1,0,0];
                            pause(0.5)
                            app.Lamp.Color = [0,0,0];
                            pause(0.5)
                        end
                    end
                    if Initial_Temperature == 100
                        for i = [0:5]
                            app.BoilingPointforWaterLamp.Color = [1,0,0];
                            pause(0.5)
                            app.BoilingPointforWaterLamp.Color = [0,0,0];
                            pause(0.5)
                        end                        
                    end
                    if Initial_Temperature <= -273.15
                        error("Enter a temperature above the absolute temperature!")
                    end
            end
            app.ConvertedTemperatureEditField.Value = Converted_Temperature;
            else
                switch Units
                case 'F'
                    switch Converted_Units
                        case 'F'
                          error("There is no conversion taking place.")
                        case 'K'
                          Temp1 = (Initial_Temperature - 32) * 5/9 + 273.15;
                          Temp2 = -32 * 5/9 + 273.15
                        case 'R'
                          Temp1 = Initial_Temperature + 459.67;
                          Temp2 = 459.67;
                        case 'C'
                          Temp1 = (Initial_Temperature - 32) *5/9; 
                          Temp2 = -32 * 5/9;
                    end
                case 'K'
                    switch Converted_Units
                        case 'F'
                            Temp1 = (Initial_Temperature - 273.15) * 9/5 + 32;
                            Temp2 = -273.15 * 9/5 + 32;
                        case 'K'
                            error("There is no conversion taking place.")
                        case 'R'
                            Temp1 = Initial_Temperature * 9/5;
                            Temp2 = 0
                        case 'C'
                            Temp1 = Initial_Temperature - 273.15;
                            Temp2 = -273.15;
                    end
                case 'R'
                    switch Converted_Units
                        case 'F'
                            Temp1 = Initial_Temperature - 459.67;
                            Temp2 = -459.67;
                        case 'K'
                            Temp1 = Initial_Temperature * 5/9;
                            Temp2 = 0;
                        case 'R'
                            error("There is no conversion taking place.")
                        case 'C'
                            Temp1 = (Initial_Temperature - 491.67) * 5/9;
                            Temp2 = -491.67 * 5/9;
                    end
                case 'C'
                    switch Converted_Units
                        case 'F'
                            Temp1 = Initial_Temperature * 9/5 + 32;
                            Temp2 = 32;
                        case 'K'
                            Temp1 = Initial_Temperature + 273.15;
                            Temp2 = 273.15;
                        case 'R'
                            Temp1 = Initial_Temperature * 9/5 + 491.67;
                            Temp2 = 491.67;
                        case 'C'
                            error("There is no conversion taking place.")
                    end
                end
                app.ConvertedTemperatureEditField.Value = Temp1 - Temp2;
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            TemperatureConverter();
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculatorTab = uitab(app.TabGroup);
            app.CalculatorTab.Title = 'Calculator';
            app.UnitsDropDownLabel = uilabel(app.CalculatorTab);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.CalculatorTab);
            app.UnitsDropDown.Items = {'F', 'K', 'R', 'C'};
            app.UnitsDropDown.Value = 'F';
            app.InitialTemperatureEditFieldLabel = uilabel(app.CalculatorTab);
            app.InitialTemperatureEditFieldLabel.Text = 'Initial Temperature';
            app.InitialTemperatureEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.ConvertButton = uibutton(app.CalculatorTab, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.UnitsDropDown_2Label = uilabel(app.CalculatorTab);
            app.UnitsDropDown_2Label.Text = 'Units';
            app.UnitsDropDown_2 = uidropdown(app.CalculatorTab);
            app.UnitsDropDown_2.Items = {'F', 'K', 'R', 'C'};
            app.UnitsDropDown_2.Value = 'F';
            app.ConvertedTemperatureEditFieldLabel = uilabel(app.CalculatorTab);
            app.ConvertedTemperatureEditFieldLabel.Text = 'Converted Temperature';
            app.ConvertedTemperatureEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.ConvertedTemperatureEditField.Editable = 'off';
            app.ChangeinTemperatureCheckBox = uicheckbox(app.CalculatorTab);
            app.ChangeinTemperatureCheckBox.Text = 'Change in Temperature';
            app.ResetButton = uibutton(app.CalculatorTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TLabel_6 = uilabel(app.CalculatorTab);
            app.TLabel_6.Text = 'T';
            app.ELabel_9 = uilabel(app.CalculatorTab);
            app.ELabel_9.Text = 'E';
            app.MLabel_3 = uilabel(app.CalculatorTab);
            app.MLabel_3.Text = 'M';
            app.ELabel_10 = uilabel(app.CalculatorTab);
            app.ELabel_10.Text = 'E';
            app.PLabel_3 = uilabel(app.CalculatorTab);
            app.PLabel_3.Text = 'P';
            app.RLabel_7 = uilabel(app.CalculatorTab);
            app.RLabel_7.Text = 'R';
            app.ALabel_3 = uilabel(app.CalculatorTab);
            app.ALabel_3.Text = 'A';
            app.TLabel_7 = uilabel(app.CalculatorTab);
            app.TLabel_7.Text = 'T';
            app.ULabel_3 = uilabel(app.CalculatorTab);
            app.ULabel_3.Text = 'U';
            app.RLabel_8 = uilabel(app.CalculatorTab);
            app.RLabel_8.Text = 'R';
            app.ELabel_11 = uilabel(app.CalculatorTab);
            app.ELabel_11.Text = 'E';
            app.CLabel_2 = uilabel(app.CalculatorTab);
            app.CLabel_2.Text = 'C';
            app.OLabel_2 = uilabel(app.CalculatorTab);
            app.OLabel_2.Text = 'O';
            app.NLabel_2 = uilabel(app.CalculatorTab);
            app.NLabel_2.Text = 'N';
            app.VLabel_2 = uilabel(app.CalculatorTab);
            app.VLabel_2.Text = 'V';
            app.ELabel_12 = uilabel(app.CalculatorTab);
            app.ELabel_12.Text = 'E';
            app.RLabel_9 = uilabel(app.CalculatorTab);
            app.RLabel_9.Text = 'R';
            app.TLabel_8 = uilabel(app.CalculatorTab);
            app.TLabel_8.Text = 'T';
            app.ELabel_13 = uilabel(app.CalculatorTab);
            app.ELabel_13.Text = 'E';
            app.RLabel_10 = uilabel(app.CalculatorTab);
            app.RLabel_10.Text = 'R';
            app.ChoosetheunitstoconvertfromandtoconverttoYoucanchoosetheLabel_2 = uilabel(app.CalculatorTab);
            app.ChoosetheunitstoconvertfromandtoconverttoYoucanchoosetheLabel_2.Text = 'Choose the units to convert from and to convert to. You can choose the "Change in Temperature" checkbox to convert change in temperatures. Click the "Convert" button to convert the temperatures. ';
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.CalculatorTab);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            app.Lamp = uilamp(app.CalculatorTab);
            app.BoilingPointforWaterLampLabel = uilabel(app.CalculatorTab);
            app.BoilingPointforWaterLampLabel.Text = 'Boiling Point (for Water)';
            app.BoilingPointforWaterLamp = uilamp(app.CalculatorTab);
            app.FreezingPointforWaterLabel = uilabel(app.CalculatorTab);
            app.FreezingPointforWaterLabel.Text = 'Freezing Point (for Water)';
            app.AppSummaryTab = uitab(app.TabGroup);
            app.AppSummaryTab.Title = 'App Summary';
            app.ChoosetheunitstoconvertfromandtoconverttoYoucanchoosetheLabel_3 = uilabel(app.AppSummaryTab);
            app.ChoosetheunitstoconvertfromandtoconverttoYoucanchoosetheLabel_3.Text = {'	The basic function of this app is to convert temperatures from one unit of measurement to another. This conversion is an example of a variety of dimensional and unit conversions that are required for chemical engineers. In the “real world”, it is quite common that one will have to collaborate with a variety of people of different specialties to complete a task or project. In this situation, one of the most preliminary requirements is to be able to know how to use the work of other professionals and make sense of it in your own domain. A well-known example of this situation is conversions. As a chemical engineer, we may be given data about the boiling or freezing point of a substance or an optimum temperature for a specific feed. If the data we received is from a foreign country or a source that utilizes different units, we need to be able to convert units to make sure we do not make a costly mistake. '; '	In my temperature conversion app, I have tried to make the app as user-friendly as possible because temperature conversions are a common calculation. On the left side are the entries for initial temperatures, and on the right are the equivalent conversions in whatever units the user chooses. The only check on the value a user can enter as their initial temperature is that the temperature has to be above 0 K. The reasoning behind this check is that 0 K is identified as “absolute” temperature and temperatures under this threshold are proven to be nonexistent. Because the app is a converter, it gives an error if the user tries to convert from one unit of temperature to the same unit. If the user wants to convert change in temperature values from one unit to another, they can click the “Change in Temperature” checkbox. Finally, there is a lamp that blinks if the user enters a value that is either the boiling or freezing point of water (to the nearest whole number).  '; ''};
            app.IbadSiddiqui2053648DrKowalLabel_2 = uilabel(app.AppSummaryTab);
            app.IbadSiddiqui2053648DrKowalLabel_2.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            app.SummaryandFeaturesLabel = uilabel(app.AppSummaryTab);
            app.SummaryandFeaturesLabel.Text = 'Summary and Features ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_App1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end