classdef isiddiq5_App10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        StandardHeatofFormationCalculatorTab  matlab.ui.container.Tab
        STANDARDHEATOFFORMATIONAPPLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        StandardHeatofReactionEditField  matlab.ui.control.NumericEditField
        StandardHeatofReactionEditFieldLabel  matlab.ui.control.Label
        CoefficientEditField           matlab.ui.control.NumericEditField
        CoefficientEditFieldLabel      matlab.ui.control.Label
        HeatofFormationEditField       matlab.ui.control.NumericEditField
        HeatofFormationEditFieldLabel  matlab.ui.control.Label
        SubstanceDropDown              matlab.ui.control.DropDown
        SubstanceDropDownLabel         matlab.ui.control.Label
        kJLabel_2                      matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        EnterDataButton                matlab.ui.control.Button
        CalculateButton                matlab.ui.control.Button
        kJLabel                        matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        AppSummaryandFeaturesTab       matlab.ui.container.Tab
        AppSummaryandFeaturesLabel     matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        HeatFormation;
        Moles;
        Substance;
        initial = [];
        REnergy = 0;
        PEnergy = 0;        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterDataButton
        function EnterDataButtonPushed(app, event)
        app.UITable.ColumnName{1} = "Heat of Formation";
        app.UITable.ColumnName{2} = "Moles";
        app.UITable.ColumnName{3} = "Substance";
            switch app.SubstanceDropDown.Value
                case "Reactant"
                    app.HeatFormation = app.HeatofFormationEditField.Value;
                    app.Moles = app.CoefficientEditField.Value;
                    app.Substance = "Reactant";
                case "Product"
                    app.HeatFormation = app.HeatofFormationEditField.Value;
                    app.Moles = app.CoefficientEditField.Value;
                    app.Substance = "Product";
            end
            data = [app.HeatFormation,app.Moles,app.Substance];
            app.initial = [app.initial;data];
            app.UITable.Data = [app.initial];
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            [r,~] = size(app.initial);
            for i = [1:r]
                if app.initial(i,3) == "Reactant"
                    app.REnergy = app.REnergy + double(app.initial(i,2))*double(app.initial(i,1));
                else
                    app.PEnergy = app.PEnergy + double(app.initial(i,2))*double(app.initial(i,1));
                end
            app.StandardHeatofReactionEditField.Value = app.PEnergy - app.REnergy;
            end            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            isiddiq5_App10();
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StandardHeatofFormationCalculatorTab = uitab(app.TabGroup);
            app.StandardHeatofFormationCalculatorTab.Title = 'Standard Heat of Formation Calculator';
            app.UITable = uitable(app.StandardHeatofFormationCalculatorTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.kJLabel = uilabel(app.StandardHeatofFormationCalculatorTab);
            app.kJLabel.Text = 'kJ';
            app.CalculateButton = uibutton(app.StandardHeatofFormationCalculatorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EnterDataButton = uibutton(app.StandardHeatofFormationCalculatorTab, 'push');
            app.EnterDataButton.ButtonPushedFcn = createCallbackFcn(app, @EnterDataButtonPushed, true);
            app.EnterDataButton.Text = 'Enter Data';
            app.ResetButton = uibutton(app.StandardHeatofFormationCalculatorTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.kJLabel_2 = uilabel(app.StandardHeatofFormationCalculatorTab);
            app.kJLabel_2.Text = 'kJ';
            app.SubstanceDropDownLabel = uilabel(app.StandardHeatofFormationCalculatorTab);
            app.SubstanceDropDownLabel.Text = 'Substance';
            app.SubstanceDropDown = uidropdown(app.StandardHeatofFormationCalculatorTab);
            app.SubstanceDropDown.Items = {'Reactant', 'Product'};
            app.SubstanceDropDown.Value = 'Reactant';
            app.HeatofFormationEditFieldLabel = uilabel(app.StandardHeatofFormationCalculatorTab);
            app.HeatofFormationEditFieldLabel.Text = 'Heat of Formation';
            app.HeatofFormationEditField = uieditfield(app.StandardHeatofFormationCalculatorTab, 'numeric');
            app.CoefficientEditFieldLabel = uilabel(app.StandardHeatofFormationCalculatorTab);
            app.CoefficientEditFieldLabel.Text = 'Coefficient ';
            app.CoefficientEditField = uieditfield(app.StandardHeatofFormationCalculatorTab, 'numeric');
            app.StandardHeatofReactionEditFieldLabel = uilabel(app.StandardHeatofFormationCalculatorTab);
            app.StandardHeatofReactionEditFieldLabel.Text = 'Standard Heat of Reaction';
            app.StandardHeatofReactionEditField = uieditfield(app.StandardHeatofFormationCalculatorTab, 'numeric');
            app.Label = uilabel(app.StandardHeatofFormationCalculatorTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.StandardHeatofFormationCalculatorTab);
            app.Label_2.Text = '';
            app.STANDARDHEATOFFORMATIONAPPLabel = uilabel(app.StandardHeatofFormationCalculatorTab);
            app.STANDARDHEATOFFORMATIONAPPLabel.Text = 'STANDARD HEAT OF FORMATION APP';
            app.AppSummaryandFeaturesTab = uitab(app.TabGroup);
            app.AppSummaryandFeaturesTab.Title = 'App Summary and Features';
            app.Label_3 = uilabel(app.AppSummaryandFeaturesTab);
            app.Label_3.Text = {'The basic function of this app is to calculate the Standard Heat of the Reaction given the standard heat of formation values for the reactants and the products. This app is quite useful because it does one of the most important calculations required to calculate the energy changes in the system. In addition to calculating the material balance of a process, Chemical Engineers also need to calculate the energy balance of a system to make sure that the heat in the system is conserved and the energy is used efficiently. To calculate standard energy balances, there are two ways to approach these problems. In the heat of reaction method, it is essential to know the standard heat of reaction of the system. In the real world, Chemical Engineers will not necessarily be given the standard heat of reactions and therefore will need to complete the calculation to obtain this value. Only when the energy balance is completed can the engineer improve the efficiency of the system. '; '	For my Standard Heat of Reaction Calculator app, I tried to make the app as simple and comprehensive as possible. The app asks the user to choose if they are entering data for a reactant or product substance. Once they make the choice, the user can enter the heat of formation at standard temperature and pressure for the substance along with the moles of the substance in the reaction. The user has to decide if they want to enter values for the heat of formation in the liquid or gaseous state based on the problem. Because it is possible that the substance is not part of the reaction, the moles of the substance can be 0. Also, the heat of formation of some of the substances are 0 and so the app allows a user to enter 0 as the value for the heat of formation for a certain substance. Only when the user enters the appropriate values will the app be able to calculate the standard heat of formation. Because most of the values of heat are calculated in kilojoules, the units for the input and output are in kilojoules. The user can click the reset button if incorrect values were entered or if they want to do another calculation.'; ''};
            app.AppSummaryandFeaturesLabel = uilabel(app.AppSummaryandFeaturesTab);
            app.AppSummaryandFeaturesLabel.Text = 'App Summary and Features';
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.UIFigure);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_App10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end