classdef isiddiq5_App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CalculatorTab                  matlab.ui.container.Tab
        YoucanclickthisbuttonmultipletimesLabel  matlab.ui.control.Label
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        gmolLabel_2                    matlab.ui.control.Label
        gmolLabel                      matlab.ui.control.Label
        AverageMolecularWeightCalculatorLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        MolecularWeightEditField       matlab.ui.control.NumericEditField
        MolecularWeightEditFieldLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        PercentWeightEditField         matlab.ui.control.NumericEditField
        PercentWeightEditFieldLabel    matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        EnterDataButton                matlab.ui.control.Button
        AverageMolecularWeightEditField  matlab.ui.control.NumericEditField
        AverageMolecularWeightEditFieldLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        WhatCalculationDropDown        matlab.ui.control.DropDown
        WhatCalculationLabel           matlab.ui.control.Label
        AppSummaryTab                  matlab.ui.container.Tab
        IbadSiddiqui2053648DrKowalLabel_2  matlab.ui.control.Label
        AppSummaryandFeaturesLabel     matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        initial = []; % Description
        weight = 0;
        percent = 0;
        final_Answer;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: EnterDataButton
        function EnterDataButtonPushed(app, event)
            app.weight = app.MolecularWeightEditField.Value;
            app.percent = app.PercentWeightEditField.Value;
            if app.percent <= 0
                error("There cannot be a component that has 0% or negative weight percentage!")
            end
            if app.weight <= 0
                error("There cannots be a component that has a 0 or negative molecular mass!")
            end
            nr = [app.weight,app.percent];
            app.initial = [app.initial;nr];
            app.UITable.Data = [app.initial];
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Type = app.WhatCalculationDropDown.Value;
            percent_to_100 = app.initial(:,2)./100;
            if sum(percent_to_100) == 1
                switch Type
                    case 'Mass Fraction'
                        Mass_Fraction = percent_to_100./app.initial(:,1);
                        app.final_Answer = 1/sum(Mass_Fraction);
                    case 'Mole Fraction'
                        app.final_Answer = sum(app.initial(:,1).* percent_to_100);
                end
            else
                error("The sum of the percent weights of the mixture need to add to 1!")
            end
            app.AverageMolecularWeightEditField.Value = app.final_Answer;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            AverageMolecularWeightCalculator();
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculatorTab = uitab(app.TabGroup);
            app.CalculatorTab.Title = 'Calculator';
            app.WhatCalculationLabel = uilabel(app.CalculatorTab);
            app.WhatCalculationLabel.Text = 'What Calculation?';
            app.WhatCalculationDropDown = uidropdown(app.CalculatorTab);
            app.WhatCalculationDropDown.Items = {'Mass Fraction', 'Mole Fraction'};
            app.WhatCalculationDropDown.Value = 'Mass Fraction';
            app.CalculateButton = uibutton(app.CalculatorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AverageMolecularWeightEditFieldLabel = uilabel(app.CalculatorTab);
            app.AverageMolecularWeightEditFieldLabel.Text = 'Average Molecular Weight';
            app.AverageMolecularWeightEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.AverageMolecularWeightEditField.Editable = 'off';
            app.EnterDataButton = uibutton(app.CalculatorTab, 'push');
            app.EnterDataButton.ButtonPushedFcn = createCallbackFcn(app, @EnterDataButtonPushed, true);
            app.EnterDataButton.Text = 'Enter Data';
            app.UITable = uitable(app.CalculatorTab);
            app.UITable.ColumnName = {'Weight'; 'Percentage'};
            app.UITable.RowName = {};
            app.PercentWeightEditFieldLabel = uilabel(app.CalculatorTab);
            app.PercentWeightEditFieldLabel.Text = 'Percent Weight';
            app.PercentWeightEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.ResetButton = uibutton(app.CalculatorTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MolecularWeightEditFieldLabel = uilabel(app.CalculatorTab);
            app.MolecularWeightEditFieldLabel.Text = 'Molecular Weight';
            app.MolecularWeightEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.Label = uilabel(app.CalculatorTab);
            app.Label.Text = '%';
            app.AverageMolecularWeightCalculatorLabel = uilabel(app.CalculatorTab);
            app.AverageMolecularWeightCalculatorLabel.Text = 'Average Molecular Weight Calculator';
            app.gmolLabel = uilabel(app.CalculatorTab);
            app.gmolLabel.Text = 'g/mol';
            app.gmolLabel_2 = uilabel(app.CalculatorTab);
            app.gmolLabel_2.Text = 'g/mol';
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.CalculatorTab);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            app.YoucanclickthisbuttonmultipletimesLabel = uilabel(app.CalculatorTab);
            app.YoucanclickthisbuttonmultipletimesLabel.Text = 'You can click this button multiple times!';
            app.AppSummaryTab = uitab(app.TabGroup);
            app.AppSummaryTab.Title = 'App Summary';
            app.Label_2 = uilabel(app.AppSummaryTab);
            app.Label_2.Text = {'        The basic function for this app is to calculate the average molecular weight of the mixture that the user enters. There are two scenarios that can be given to a user to have them calculate the average molecular weight. A user can be given the mass fraction or the mole fraction. The concept motivating this app is to use the correct formula to generate the average molecular weight for the user based on the values they are given. This particular calculation is important for chemical engineers because process stream often have mixtures of a variety of compounds. If there is a fault in the stream, or perhaps an advantageous property of one of the materials in the stream, it is important to know how much the substance is contributing to the overall molar mass. Additionally, for prospective chemical engineers, by calculating the average molecular weight of the mixture, they can easily know the molar mass of the mixture (supposing the mass of the mixture is given). Knowing the molar mass of the mixture can be very useful in doing problems that involve process streams. '; '	In my Average Molecular Weight Calculator app, I tried to make the app as open-ended as possible. Firstly, the user is prompted to choose what type of values they will enter (mass fractions or mole fractions). This allows me to know what the calculation needs to be to get the user the correct answer. I have chosen to use a table in the MATLAB app designer because it allows the user to enter as many data values as they are given for their percentages and molar masses. Each time the user enters a molecular weight value and a percentage, they can their data into the list. I have chosen to have my user enter percentages for the mass or mole fraction because it is common to see these values given as a percentage in a problem. To make sure the user is entering reasonable values, I have included a check that gives an error when the user enters a value less than or equal to 0 for either the molecular weight or the percentage. Neither of these entries are physically possible. When the user has entered all of their values, they can then click the “Calculate” button to generate the answer. When clicking this button, I have made sure to check that all the percentages add up to 100% to ensure that the user has entered all components of the mixture. Subsequently, my app simply generates a solution for the user to use in their necessary calculations. '; ''};
            app.AppSummaryandFeaturesLabel = uilabel(app.AppSummaryTab);
            app.AppSummaryandFeaturesLabel.Text = 'App Summary and Features';
            app.IbadSiddiqui2053648DrKowalLabel_2 = uilabel(app.AppSummaryTab);
            app.IbadSiddiqui2053648DrKowalLabel_2.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_App2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end