classdef isiddiq5_App3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ConverterTab                matlab.ui.container.Tab
        UpdatesEditField            matlab.ui.control.EditField
        UpdatesEditFieldLabel       matlab.ui.control.Label
        MoleFractionEditField       matlab.ui.control.NumericEditField
        MoleFractionEditFieldLabel  matlab.ui.control.Label
        MassFractionEditField       matlab.ui.control.NumericEditField
        MassFractionEditFieldLabel  matlab.ui.control.Label
        MolarMassEditField          matlab.ui.control.NumericEditField
        MolarMassEditFieldLabel     matlab.ui.control.Label
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        FractiontoFractionConverterLabel  matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        Switch                      matlab.ui.control.Switch
        CalculateButton             matlab.ui.control.Button
        EnterDataButton             matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        AppSummaryTab               matlab.ui.container.Tab
        AppSummaryandFeaturesLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        MolarMass;
        Fraction;
        initial = [];
        value = "Mass Fraction to Mole Fraction";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterDataButton
        function EnterDataButtonPushed(app, event)
            app.Switch.Enable = "off";
            app.MolarMass = app.MolarMassEditField.Value;
            app.UITable.ColumnName{1} = "Molar Mass";
            if app.value == "Mass Fraction to Mole Fraction"
                app.UITable.ColumnName{2} = "Mass Fraction";
                app.Fraction = app.MassFractionEditField.Value;
            else
                app.UITable.ColumnName{2} = "Mole Fraction";
                app.Fraction = app.MoleFractionEditField.Value;
            end
            if app.MolarMassEditField.Value <= 0
                app.UpdatesEditField.Value = "There cannot be negative molar masses! Reset the app";
                error(" ")
            end
            if app.MassFractionEditField.Value > 1 || app.MoleFractionEditField.Value > 1
                app.UpdatesEditField.Value = "The fraction for a single species cannot be greater than 1! Reset the app";
                error(" ")
            end
            if app.MassFractionEditField.Value < 0 || app.MoleFractionEditField.Value < 0
                app.UpdatesEditField.Value = "There cannot be negative fractions! Reset the app"
                error(" ")
            end
            data = [app.MolarMass,app.Fraction];
            app.initial = [app.initial;data];
            app.UITable.Data = [app.initial];
            if sum(app.initial(:,2)) == 1
                app.CalculateButton.Enable = "on";
                app.UpdatesEditField.Value = "Click the Calculate button to do your calcuation!"
            end
            if sum(app.initial(:,2)) > 1
                app.UpdatesEditField.Value = "Your weight fractions add up to a number greater than 1, Press Reset!";
                error(" ")
            end
            if sum(app.initial(:,2)) < 1
                app.UpdatesEditField.Value = "Keep putting in more data to get the fraction to equal 1."
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if app.value == "Mole Fraction to Mass Fraction"
                app.UITable.ColumnName{3} = "Mass Fraction";
                Mass = (app.initial(:,2).*100).*app.initial(:,1);
                TotalMass = sum(Mass);
                MassFractions = Mass./TotalMass;
                app.UITable.Data = [app.initial,MassFractions];
                app.UITable.ColumnName{3} = "Mass Fraction";
            else
                    Moles = (app.initial(:,2).*100)./app.initial(:,1);
                    TotalMoles = sum(Moles);
                    MoleFractions = Moles./TotalMoles;
                    app.UITable.Data = [app.initial,MoleFractions];
                    app.UITable.ColumnName{3} = "Mole Fraction";
            end
            app.UpdatesEditField.Value = "Press Reset to try a new calculation!";
        end

        % Callback function
        function OptionDropDownOpening(app, event)

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.value = app.Switch.Value;
            if app.value == "Mole Fraction to Mass Fraction"
                app.MassFractionEditField.Enable = "off";
                app.MoleFractionEditField.Enable = "on";
            else
                app.MoleFractionEditField.Enable = "off";
                app.MassFractionEditField.Enable = "on";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            isiddiq5_App3();
            close(app.UIFigure)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConverterTab = uitab(app.TabGroup);
            app.ConverterTab.Title = 'Converter';
            app.UITable = uitable(app.ConverterTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.EnterDataButton = uibutton(app.ConverterTab, 'push');
            app.EnterDataButton.ButtonPushedFcn = createCallbackFcn(app, @EnterDataButtonPushed, true);
            app.EnterDataButton.Text = 'Enter Data';
            app.CalculateButton = uibutton(app.ConverterTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.Switch = uiswitch(app.ConverterTab, 'slider');
            app.Switch.Items = {'Mass Fraction to Mole Fraction ', 'Mole Fraction to Mass Fraction'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Mass Fraction to Mole Fraction ';
            app.ResetButton = uibutton(app.ConverterTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FractiontoFractionConverterLabel = uilabel(app.ConverterTab);
            app.FractiontoFractionConverterLabel.Text = 'Fraction to Fraction Converter';
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.ConverterTab);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            app.MolarMassEditFieldLabel = uilabel(app.ConverterTab);
            app.MolarMassEditFieldLabel.Text = 'Molar Mass';
            app.MolarMassEditField = uieditfield(app.ConverterTab, 'numeric');
            app.MassFractionEditFieldLabel = uilabel(app.ConverterTab);
            app.MassFractionEditFieldLabel.Text = 'Mass Fraction';
            app.MassFractionEditField = uieditfield(app.ConverterTab, 'numeric');
            app.MoleFractionEditFieldLabel = uilabel(app.ConverterTab);
            app.MoleFractionEditFieldLabel.Text = 'Mole Fraction';
            app.MoleFractionEditField = uieditfield(app.ConverterTab, 'numeric');
            app.MoleFractionEditField.Enable = 'off';
            app.UpdatesEditFieldLabel = uilabel(app.ConverterTab);
            app.UpdatesEditFieldLabel.Text = 'Updates';
            app.UpdatesEditField = uieditfield(app.ConverterTab, 'text');
            app.UpdatesEditField.Value = 'Toggle which calculation you want to do and enter the data for that calculation.';
            app.AppSummaryTab = uitab(app.TabGroup);
            app.AppSummaryTab.Title = 'App Summary';
            app.Label = uilabel(app.AppSummaryTab);
            app.Label.Text = {'	The basic function of this app is to convert the given mass fractions to mole fractions or to convert the mole fraction to mass fractions. The motivation behind this app is that Chemical Engineers need to be working with a composition of the stream or processes they are assigned. Depending on the stream, some compositions can be given in terms of mass fractions and others in mole fractions in the same process. Even if all of the compositions of the streams in the process unit are given in terms of mass fractions or all of them are given in mole fractions, the flow rates of certain streams could be given in terms of a different fraction. Thus, it is essential for a Chemical Engineer to be able to readily work with different fractions given and because of the frequent number of times that he or she may encounter the need to convert these fractions. From this rather simple calculation that the Chemical Engineer can know the correct composition of the streams in the process unit. '; '	In my Fraction-to-Fraction Converter app, I tried to make the app as open-ended and user friendly as possible. The user is first required to use the toggle in the bottom to decide what calculation they want the app to be doing. Depending on which way the user flicks the toggle, they are required to enter different set of values. For example, if the user wants the app to convert from mass fractions to mole fractions, they should flip the toggle the correct way and they will be prompted to enter the mass fractions and molecular weight for each component. If the user wants to convert from mole fractions to mass fractions, they are prompted to enter the given mole fractions and molecular weights for each component. When the user enters the two pieces of data for a single component, they should click the enter button to enter the data into the table. If the user enters fractions that are not between 0 and 1 or if they enter molecular weights below 0, the app throws an error message and crashes. The user is required to reset the app if they are to carry out calculations. When the fractions of the user add up to 1, the user can click the calculate button to carry out their calculation. If the user keeps adding data and the sum of the fractions exceeds 1, the app throws an error message and crashes. Once the user is done with a conversion calculation, they have to reset the app to carry out another conversion. '; ''};
            app.AppSummaryandFeaturesLabel = uilabel(app.AppSummaryTab);
            app.AppSummaryandFeaturesLabel.Text = 'App Summary and Features';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_App3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end