classdef isiddiq5_App4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimpleSeparatorCalculatorTab    matlab.ui.container.Tab
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        SimpleSeparatorCalculatorLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        Species2FractionEditField_3     matlab.ui.control.NumericEditField
        Species2FractionEditField_3Label  matlab.ui.control.Label
        Species1FractionEditField_3     matlab.ui.control.NumericEditField
        Species1FractionEditField_3Label  matlab.ui.control.Label
        Species1FractionEditField_2Label  matlab.ui.control.Label
        Species1FractionEditField_2     matlab.ui.control.NumericEditField
        Species1FractionEditFieldLabel  matlab.ui.control.Label
        Species1FractionEditField       matlab.ui.control.NumericEditField
        OutputStream2EditFieldLabel     matlab.ui.control.Label
        OutputStream2EditField          matlab.ui.control.NumericEditField
        WarningsEditField               matlab.ui.control.EditField
        WarningsEditFieldLabel          matlab.ui.control.Label
        Species2FractionEditField_2     matlab.ui.control.NumericEditField
        Species2FractionEditField_2Label  matlab.ui.control.Label
        Species2FractionEditField       matlab.ui.control.NumericEditField
        Species2FractionEditFieldLabel  matlab.ui.control.Label
        OutputStream1EditField          matlab.ui.control.NumericEditField
        OutputStream1EditFieldLabel     matlab.ui.control.Label
        InputStreamEditField            matlab.ui.control.NumericEditField
        InputStreamEditFieldLabel       matlab.ui.control.Label
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        CalculateButton                 matlab.ui.control.Button
        SeparatorLabel                  matlab.ui.control.Label
        AppSummaryTab                   matlab.ui.container.Tab
        AppSummaryandFeaturesLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        Unknowns = 0;
        PhysicalProperties = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
            app.InputStreamEditField.Enable = "on";
            else 
                app.InputStreamEditField.Enable = "off";
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value == 1 
            app.Species1FractionEditField_3.Enable = "on";
            else
                app.Species1FractionEditField_3.Enable = "off";
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            if value == 1
            app.Species2FractionEditField_3.Enable = "on";
            else
                app.Species2FractionEditField_3.Enable = "off";
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            if value == 1 
            app.OutputStream1EditField.Enable = "on";
            else
                app.OutputStreamEditField.Enable = "off";
            end
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            if value == 1
            app.Species1FractionEditField.Enable = "on";
            else
                app.Species1FractionEditField.Enable = "off";
            end
            
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            if value == 1
            app.Species2FractionEditField.Enable = "on";
            else 
                app.Species2FractionEditField.Enable = "off";
            end
            
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            if value == 1
                app.OutputStream2EditField.Enable = "on";
            else
                app.OutputStream2EditField.Enable = "off";
            end
            
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
            if value == 1
            app.Species1FractionEditField_2.Enable = "on";
            else
                app.Species1FractionEditField_2.Enable = "off";
            end
            
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            value = app.CheckBox_10.Value;
            if value == 1
            app.Species2FractionEditField_2.Enable = "on";
            else
                app.Species2FractionEditField_2.Enable = "off";
            end
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            DOF = 0;
            Input_S1 = 0;
            Input_S2 = 0;
            Output_S1 = 0;
            Output_S2 = 0;
            Output2_S1 = 0;
            Output2_S2 = 0;
            Input_S1 = app.Species1FractionEditField_3.Value;
            Input_S2 = app.Species2FractionEditField_3.Value;
            Output_S1 = app.Species1FractionEditField.Value;
            Output_S2 = app.Species2FractionEditField.Value;
            Output2_S1 = app.Species1FractionEditField_2.Value;
            Output2_S2 = app.Species2FractionEditField_2.Value;
            
            if app.Species1FractionEditField.Value > 1 || app.Species2FractionEditField.Value > 1 || app.Species1FractionEditField_2.Value > 1 || app.Species2FractionEditField_2.Value > 1 || app.Species2FractionEditField_3.Value > 1 || app.Species1FractionEditField_3.Value > 1
                app.WarningsEditField.Value = "The fraction of a species cannot be greater than 1!";
                error(" ")
            end
            if app.Species1FractionEditField.Value < 0 || app.Species2FractionEditField.Value < 0 || app.Species1FractionEditField_2.Value < 0 || app.Species2FractionEditField_2.Value < 0 || app.Species2FractionEditField_3.Value < 0 || app.Species1FractionEditField_3.Value < 0
                app.WarningsEditField.Value = "The fraction of a species cannot be less than 0!";
                error(" ")
            end
            if (app.CheckBox_5.Value == 1 && app.CheckBox_6.Value == 1 && app.Species1FractionEditField.Value + app.Species2FractionEditField.Value ~= 1) || (app.CheckBox_9.Value == 1 && app.CheckBox_10.Value == 1 && app.Species1FractionEditField_2.Value + app.Species2FractionEditField_2.Value ~= 1) || (app.CheckBox_2.Value == 1 && app.CheckBox_3.Value == 1 && app.Species1FractionEditField_3.Value + app.Species2FractionEditField_3.Value ~= 1)
                app.WarningsEditField.Value = "The fractions of both species need to add up to 1!"
                error(" ")
            end
            if app.InputStreamEditField.Value < 0 || app.OutputStream1EditField.Value < 0 || app.OutputStream2EditField.Value < 0 
                app.WarningsEditField.Value = "The stream cannot have a negative value!";
                error(" ")
            end
            if app.CheckBox_2.Value ~= app.CheckBox_3.Value || (app.CheckBox_2.Value == 0 && app.CheckBox_3.Value == 0)
                app.PhysicalProperties = app.PhysicalProperties + 1;
            end
            
            if app.CheckBox_2.Value ~= app.CheckBox_3.Value 
                if app.CheckBox_2.Value == 1
                    Input_S1 = app.Species1FractionEditField_3.Value;
                    Input_S2 = 1 - app.Species1FractionEditField_3.Value;
                    app.Species2FractionEditField_3.Value = Input_S2;
                else
                    Input_S2 = app.Species2FractionEditField_3.Value;
                    Input_S1 = 1 - Input_S2;
                    app.Species1FractionEditField_3.Value = Input_S1;
                end
            end
            
            if app.CheckBox.Value == 1 && app.CheckBox_4 == 1 & app.CheckBox_7 == 1 & app.InputStreamEditField.Value ~= app.OutputStream1EditField.Value + app.OutputStream2EditField.Value
                app.WarningsEditField.Value = "The output streams need to add up to the input stream! Reset the app."
                error(" ")
            end
            
            if (app.CheckBox_5.Value == 0 && app.CheckBox_6.Value == 0) || app.CheckBox_5.Value ~= app.CheckBox_6.Value
                app.PhysicalProperties = app.PhysicalProperties + 1;
            end
            
            if app.CheckBox_5.Value ~= app.CheckBox_6.Value
                if app.CheckBox_5.Value == 1
                    Output_S1 = app.Species1FractionEditField.Value;
                    Output_S2 = 1 - Output_S1;
                    app.Species2FractionEditField.Value = Output_S2;
                else
                    Output_S2 = app.Species2FractionEditField.Value;
                    Output_S1 = 1 - Output_S2;
                    app.Species1FractionEditField.Value = Output_S1;
                end
            end
            
            if (app.CheckBox_9.Value == 0 && app.CheckBox_10.Value == 0) || app.CheckBox_9.Value ~= app.CheckBox_10.Value
                app.PhysicalProperties = app.PhysicalProperties + 1;
            end
            
            if app.CheckBox_9.Value ~= app.CheckBox_10.Value
                if app.CheckBox_9.Value == 1
                    Output2_S1 = app.Species1FractionEditField_2.Value;
                    Output2_S2 = 1 - Output2_S1;
                    app.Species2FractionEditField_2.Value = Output2_S2;
                else
                    Output2_S2 = app.Species2FractionEditField_2.Value;
                    Output2_S1 = 1 - Output2_S2;
                    app.Species1FractionEditField_2.Value = Output2_S1;
                end
            end
            
            if app.CheckBox.Value == 0
                app.Unknowns = app.Unknowns + 1;
            end
            if app.CheckBox_2.Value == 0
                app.Unknowns = app.Unknowns + 1;
            end
            if app.CheckBox_3.Value == 0
                app.Unknowns = app.Unknowns + 1;
            end
            if app.CheckBox_4.Value == 0
                app.Unknowns = app.Unknowns + 1;
            end
            if app.CheckBox_5.Value == 0
                app.Unknowns = app.Unknowns + 1;
            end
            if app.CheckBox_6.Value == 0
                app.Unknowns = app.Unknowns + 1;
            end
            if app.CheckBox_7.Value == 0
                app.Unknowns = app.Unknowns + 1;
            end
            if app.CheckBox_9.Value == 0
                app.Unknowns = app.Unknowns + 1;
            end
            if app.CheckBox_10.Value == 0
                app.Unknowns = app.Unknowns + 1;
            end
            Output = app.OutputStream1EditField.Value + app.OutputStream2EditField.Value;
            DOF = app.Unknowns - app.PhysicalProperties - 2
            app.Unknowns
            app.PhysicalProperties
            if DOF > 0 
                app.WarningsEditField.Value = "The degrees of freedom is greater than 0, provide more information";
                error(" ")
            end

           if app.CheckBox.Value == 0 & app.CheckBox_4.Value == 1 & app.CheckBox_7.Value == 1
               app.InputStreamEditField.Value = Output;
               
           end
           if app.CheckBox.Value == 1 & app.CheckBox_4.Value == 0 & app.CheckBox_7.Value == 1
               if app.OutputStream2EditField >= app.InputStreamEditField.Value
                   app.WarningsEditField.Value = "The output stream cannot be greater than the input stream!";
                   error(" ")
               end
               app.OutputStream1EditField.Value = app.InputStreamEditField.Value - app.OutputStream2EditField.Value;
           end
           if app.CheckBox.Value == 1 && app.CheckBox_4.Value == 1 & app.CheckBox_7.Value == 0
               if app.OutputStream1EditField.Value >= app.InputStreamEditField.Value
                   app.WarningsEditField.Value = "The output stream cannot be greater than the input stream!";
                   error(" ")
               end
               app.OutputStream2EditField.Value = app.InputStreamEditField.Value - app.OutputStream1EditField.Value;
           end
           if app.CheckBox_4.Value == 0 && app.CheckBox_7.Value == 0 
               B = [app.InputStreamEditField.Value*Input_S1;app.InputStreamEditField.Value*Input_S2;app.InputStreamEditField.Value];
               A = [Output_S1,Output2_S1;Output_S2,Output2_S2;1,1];
               Answer = linsolve(A,B);
               app.OutputStream1EditField.Value = Answer(1);
               app.OutputStream2EditField.Value = Answer(2);
           end
           if app.CheckBox.Value == 0 && app.CheckBox_4.Value == 0
               A = [app.Species1FractionEditField_3.Value, app.Species1FractionEditField.Value * (-1); app.Species2FractionEditField_3.Value, app.Species2FractionEditField.Value * (-1); 1,-1]
               B = [app.Species1FractionEditField_2.Value*app.OutputStream2EditField.Value; app.Species2FractionEditField_2.Value*app.OutputStream2EditField.Value; app.OutputStream2EditField.Value]
               Answer = linsolve(A,B);
               app.OutputStream1EditField.Value = Answer(2);
               app.InputStreamEditField.Value = Answer(1);
           end
           if app.CheckBox.Value == 0 && app.CheckBox_7.Value == 0
               A = [app.Species1FractionEditField_3.Value,app.Species1FractionEditField_2.Value * (-1); app.Species2FractionEditField_3.Value,app.Species2FractionEditField_2.Value * (-1); 1,-1];
               B = [app.OutputStream1EditField.Value* app.Species1FractionEditField.Value; app.OutputStream1EditField.Value*app.Species2FractionEditField.Value; app.OutputStream1EditField.Value];
               Answer = linsolve(A,B);
               app.OutputStream2EditField.Value = Answer(2);
               app.InputStreamEditField.Value = Answer(1);
           end
           if app.CheckBox.Value == 0 && app.CheckBox_2.Value == 0 && app.CheckBox_3.Value == 0
               app.Species1FractionEditField_3.Value = (app.OutputStream1EditField.Value * app.Species1FractionEditField.Value + app.OutputStream2EditField.Value * app.Species1FractionEditField_2.Value) / app.InputStreamEditField.Value;
               app.Species2FractionEditField_3.Value = 1 - app.Species1FractionEditField_3.Value;
           end
           if app.CheckBox_4.Value == 0 && app.CheckBox_5.Value == 0 && app.CheckBox_6.Value == 0
               app.Species1FractionEditField.Value = (app.InputStreamEditField.Value*app.Species1FractionEditField_3.Value-app.OutputStream2EditField.Value*app.Species1FractionEditField_2.Value) / app.OutputStream1EditField.Value;
               app.Species2FractionEditField.Value = 1-app.Species1FractionEditField.Value;
           end
           if app.CheckBox_7.Value == 0 && app.CheckBox_10.Value == 0 && app.CheckBox_9.Value == 0
               app.Species1FractionEditField_2.Value = (app.InputStreamEditField.Value*app.Species1FractionEditField_3.Value-app.OutputStream1EditField.Value*app.Species1FractionEditField.Value) / app.OutputStream2EditField.Value;
               app.Species2FractionEditField_2.Value = 1 - app.Species1FractionEditField_2.Value;
           end
           app.Species1FractionEditField_2.Value = ((app.InputStreamEditField.Value * app.Species1FractionEditField_3.Value) - (app.OutputStream1EditField.Value*app.Species1FractionEditField.Value))/app.OutputStream2EditField.Value;
           app.Species2FractionEditField_2.Value = 1 - app.Species1FractionEditField_2.Value;
           app.Species1FractionEditField.Value = ((app.InputStreamEditField.Value * app.Species1FractionEditField_3.Value) - (app.OutputStream2EditField.Value*app.Species1FractionEditField_2.Value)) /app.OutputStream1EditField.Value;
           app.Species2FractionEditField.Value = 1 - app.Species1FractionEditField.Value;
           app.Species1FractionEditField_3.Value = ((app.OutputStream1EditField.Value*app.Species1FractionEditField.Value) + (app.OutputStream2EditField.Value*app.Species1FractionEditField_2.Value)) /app.InputStreamEditField.Value;
           app.Species2FractionEditField_3.Value = 1 - app.Species1FractionEditField_3.Value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            isiddiq5_App4();
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimpleSeparatorCalculatorTab = uitab(app.TabGroup);
            app.SimpleSeparatorCalculatorTab.Title = 'Simple Separator Calculator';
            app.SeparatorLabel = uilabel(app.SimpleSeparatorCalculatorTab);
            app.SeparatorLabel.Text = 'Separator';
            app.CalculateButton = uibutton(app.SimpleSeparatorCalculatorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CheckBox = uicheckbox(app.SimpleSeparatorCalculatorTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.SimpleSeparatorCalculatorTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.SimpleSeparatorCalculatorTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.SimpleSeparatorCalculatorTab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.SimpleSeparatorCalculatorTab);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.SimpleSeparatorCalculatorTab);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.SimpleSeparatorCalculatorTab);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CheckBox_9 = uicheckbox(app.SimpleSeparatorCalculatorTab);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.SimpleSeparatorCalculatorTab);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '';
            app.InputStreamEditFieldLabel = uilabel(app.SimpleSeparatorCalculatorTab);
            app.InputStreamEditFieldLabel.Text = 'Input Stream';
            app.InputStreamEditField = uieditfield(app.SimpleSeparatorCalculatorTab, 'numeric');
            app.InputStreamEditField.Enable = 'off';
            app.OutputStream1EditFieldLabel = uilabel(app.SimpleSeparatorCalculatorTab);
            app.OutputStream1EditFieldLabel.Text = 'Output Stream 1';
            app.OutputStream1EditField = uieditfield(app.SimpleSeparatorCalculatorTab, 'numeric');
            app.OutputStream1EditField.Enable = 'off';
            app.Species2FractionEditFieldLabel = uilabel(app.SimpleSeparatorCalculatorTab);
            app.Species2FractionEditFieldLabel.Text = 'Species 2 Fraction';
            app.Species2FractionEditField = uieditfield(app.SimpleSeparatorCalculatorTab, 'numeric');
            app.Species2FractionEditField.Enable = 'off';
            app.Species2FractionEditField_2Label = uilabel(app.SimpleSeparatorCalculatorTab);
            app.Species2FractionEditField_2Label.Text = 'Species 2 Fraction';
            app.Species2FractionEditField_2 = uieditfield(app.SimpleSeparatorCalculatorTab, 'numeric');
            app.Species2FractionEditField_2.Enable = 'off';
            app.WarningsEditFieldLabel = uilabel(app.SimpleSeparatorCalculatorTab);
            app.WarningsEditFieldLabel.Text = 'Warnings';
            app.WarningsEditField = uieditfield(app.SimpleSeparatorCalculatorTab, 'text');
            app.OutputStream2EditField = uieditfield(app.SimpleSeparatorCalculatorTab, 'numeric');
            app.OutputStream2EditField.Enable = 'off';
            app.OutputStream2EditFieldLabel = uilabel(app.SimpleSeparatorCalculatorTab);
            app.OutputStream2EditFieldLabel.Text = 'Output Stream 2';
            app.Species1FractionEditField = uieditfield(app.SimpleSeparatorCalculatorTab, 'numeric');
            app.Species1FractionEditField.Enable = 'off';
            app.Species1FractionEditFieldLabel = uilabel(app.SimpleSeparatorCalculatorTab);
            app.Species1FractionEditFieldLabel.Text = 'Species 1 Fraction';
            app.Species1FractionEditField_2 = uieditfield(app.SimpleSeparatorCalculatorTab, 'numeric');
            app.Species1FractionEditField_2.Enable = 'off';
            app.Species1FractionEditField_2Label = uilabel(app.SimpleSeparatorCalculatorTab);
            app.Species1FractionEditField_2Label.Text = 'Species 1 Fraction';
            app.Species1FractionEditField_3Label = uilabel(app.SimpleSeparatorCalculatorTab);
            app.Species1FractionEditField_3Label.Text = 'Species 1 Fraction';
            app.Species1FractionEditField_3 = uieditfield(app.SimpleSeparatorCalculatorTab, 'numeric');
            app.Species1FractionEditField_3.Enable = 'off';
            app.Species2FractionEditField_3Label = uilabel(app.SimpleSeparatorCalculatorTab);
            app.Species2FractionEditField_3Label.Text = 'Species 2 Fraction';
            app.Species2FractionEditField_3 = uieditfield(app.SimpleSeparatorCalculatorTab, 'numeric');
            app.Species2FractionEditField_3.Enable = 'off';
            app.ResetButton = uibutton(app.SimpleSeparatorCalculatorTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SimpleSeparatorCalculatorLabel = uilabel(app.SimpleSeparatorCalculatorTab);
            app.SimpleSeparatorCalculatorLabel.Text = 'Simple Separator Calculator ';
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.SimpleSeparatorCalculatorTab);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            app.AppSummaryTab = uitab(app.TabGroup);
            app.AppSummaryTab.Title = 'App Summary';
            app.Label = uilabel(app.AppSummaryTab);
            app.Label.Text = {'The basic function of this app is to find the unknowns in a simple two species separator. The motivation behind this app is that separators are one of the most important types of processes that Chemical Engineers have to encounter in their professional careers. Whether it be Evaporation, Distillation, Filtration or Sedimentation, Chemical Engineers have to be familiar with the basic separator process so that they are able to apply their skills on more complicated processes. Knowing the calculations given various different unknowns in a simple separator can be beneficial for Chemical Engineers as it solidifies the basics for the types of calculations that they may encounter in the future. As their career progresses, they can build upon the basics that they have established. '; 'In my Simple Separator Calculator, I tried to make the app as simple as possible. The user is required to check the boxes depending on what they are given in their problem statements. If the user clicks a check box, they are required to enter a value for the corresponding data field. The unchecked boxes count as unknowns. If the user clicks the “Calculate” button and the Degrees of Freedoms of in the separator are above 0, then the “Warnings” text box shows that the Degrees of Freedom in the problem are above 0 and the problem is not solvable. The user would need to reset the app and enter in more data. All the values entered in the problem have to be in terms of mass or moles. This is not only to simplify an already complicated app, but because the user can use my previous app to carry out conversions between mass and mole fractions. Logically, the app throws an error if an unreasonable value is inputted as data (fractions not between 0 and 1, flow rates below 0). The user is required to reset the app and re-enter their data if this is the case. Further, if the user decides to enter both the fractions for a stream, the fractions need to add up to 1 for the app to carry out the calculation. Finally, if the user decides to enter data for the flow of two or all three of the streams, the app checks if the input flow equals the sum of the output flows. If the user enters a value in the input flow that is smaller than their value for the output flow, the app throws an error. For cases in which the app has to solve a system of equations, I used the linsolve function to find the unknowns. If the data goes through all these checks and the problem is solvable, the unknowns appear in their respective editfields. '; ''};
            app.AppSummaryandFeaturesLabel = uilabel(app.AppSummaryTab);
            app.AppSummaryandFeaturesLabel.Text = 'App Summary and Features';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_App4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end