classdef isiddiq5_App5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        LimitingReactantCalculatorTab   matlab.ui.container.Tab
        ResetButton                     matlab.ui.control.Button
        LIMITINGREACTANTCALCULATORLabel  matlab.ui.control.Label
        LimitingReactantEditField       matlab.ui.control.EditField
        LimitingReactantEditFieldLabel  matlab.ui.control.Label
        EnterDataButton                 matlab.ui.control.Button
        MolarMassEditField              matlab.ui.control.NumericEditField
        MolarMassEditFieldLabel         matlab.ui.control.Label
        CoefficientinreactionEditField  matlab.ui.control.NumericEditField
        CoefficientinreactionEditFieldLabel  matlab.ui.control.Label
        MassofCompoundEditField         matlab.ui.control.NumericEditField
        MassofCompoundEditFieldLabel    matlab.ui.control.Label
        MolesofCompoundEditField        matlab.ui.control.NumericEditField
        MolesofCompoundEditFieldLabel   matlab.ui.control.Label
        EnteringMassesCheckBox          matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        CalculateButton                 matlab.ui.control.Button
        AppSummaryTab                   matlab.ui.container.Tab
        AppSummaryandFeaturesLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        initial = [];
        Moles
        MOR = [];
        number = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            index = find(app.MOR == min(app.MOR));
%             [~, index] = min(app.MOR,,"all");
            text = "";
            if size(index) == 1
                app.LimitingReactantEditField.Value = index + " is the Limiting Reactant.";
            else
                for i=1:length(index)
                    if i == length(index)
                        text = text + index(i) + " is the Limiting Reactant.";
                    else
                        text = text + index(i) + " is the Limiting Reactant and ";
                    end    
                end
                app.LimitingReactantEditField.Value = text;
            end            
            s1 = uistyle;
            s1.BackgroundColor = "Yellow";
            app.UITable.addStyle(s1,"row",index)
            app.CalculateButton.Enable = "off";
            app.EnterDataButton.Enable = "off";
            app.ResetButton.Visible = "on";
        end

        % Button pushed function: EnterDataButton
        function EnterDataButtonPushed(app, event)
            app.number = app.number + 1;
            app.UITable.ColumnName{1} = "#";
            app.UITable.ColumnName{2} = "Moles of Reactant";
            app.UITable.ColumnName{3} = "Coefficient in Reaction";
            if app.CoefficientinreactionEditField.Value <=0
                app.ResetButton.Visible = "on";
                error("The coefficient in the reaction cannot be less than or equal to 0.")
            end
            if app.EnteringMassesCheckBox.Value == 1
                if app.MassofCompoundEditField.Value<= 0 
                    app.ResetButton.Visible = "on";
                    error("The mass of the compound cannot be less than or equal to 0.")
                end
                if app.MolarMassEditField.Value<=0
                    app.ResetButton.Visible = "on";
                    error("The molar mass of a substance cannot be less than or equal to 0")
                end
                app.Moles = app.MassofCompoundEditField.Value/app.MolarMassEditField.Value;
            else
                app.Moles = app.MolesofCompoundEditField.Value;
            end
            if app.Moles <= 0
                error("The moles of the compound cannot be 0 or less than 0")
            end
            data = [app.number,app.Moles,app.CoefficientinreactionEditField.Value];
            app.initial = [app.initial;data];
            app.UITable.Data = [app.initial];
            app.MOR = [app.MOR,app.Moles/app.CoefficientinreactionEditField.Value];
        end

        % Value changed function: EnteringMassesCheckBox
        function EnteringMassesCheckBoxValueChanged(app, event)
            value = app.EnteringMassesCheckBox.Value;
            if value == 1
                app.MolarMassEditField.Visible = "on";
                app.MolarMassEditFieldLabel.Visible = "on";
                app.MolarMassEditField.Enable = "on";
                app.MolarMassEditFieldLabel.Enable = "on";
                app.MolarMassEditField.Editable = "on";
                app.MassofCompoundEditField.Visible = "on";
                app.MassofCompoundEditFieldLabel.Visible = "on";
                app.MassofCompoundEditField.Editable = "on";
                app.MassofCompoundEditField.Enable = "on";
                app.MassofCompoundEditFieldLabel.Enable = "on";
                app.MolesofCompoundEditField.Enable = "off";
                app.MolesofCompoundEditField.Visible = "off";
                app.MolesofCompoundEditFieldLabel.Visible = "off";
            else
                app.MolesofCompoundEditField.Value = 0;
                app.MolarMassEditField.Visible = "off";
                app.MolarMassEditFieldLabel.Visible = "off";
                app.MolarMassEditField.Enable = "off";
                app.MolarMassEditFieldLabel.Enable = "off";
                app.MolarMassEditField.Editable = "off";
                app.MassofCompoundEditField.Visible = "off";
                app.MassofCompoundEditFieldLabel.Visible = "off";
                app.MassofCompoundEditField.Editable = "off";
                app.MassofCompoundEditField.Enable = "off";
                app.MassofCompoundEditFieldLabel.Enable = "off";  
                app.MolesofCompoundEditField.Enable = "on";
                app.MolesofCompoundEditField.Visible = "on";
                app.MolesofCompoundEditFieldLabel.Visible = "on";
            end
%             app.EnteringMassesCheckBox.Enable = "off";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            isiddiq5_App5();
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LimitingReactantCalculatorTab = uitab(app.TabGroup);
            app.LimitingReactantCalculatorTab.Title = 'Limiting Reactant Calculator ';
            app.CalculateButton = uibutton(app.LimitingReactantCalculatorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate ';
            app.UITable = uitable(app.LimitingReactantCalculatorTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.EnteringMassesCheckBox = uicheckbox(app.LimitingReactantCalculatorTab);
            app.EnteringMassesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnteringMassesCheckBoxValueChanged, true);
            app.EnteringMassesCheckBox.Text = 'Entering Masses?';
            app.MolesofCompoundEditFieldLabel = uilabel(app.LimitingReactantCalculatorTab);
            app.MolesofCompoundEditFieldLabel.Text = 'Moles of Compound';
            app.MolesofCompoundEditField = uieditfield(app.LimitingReactantCalculatorTab, 'numeric');
            app.MassofCompoundEditFieldLabel = uilabel(app.LimitingReactantCalculatorTab);
            app.MassofCompoundEditFieldLabel.Enable = 'off';
            app.MassofCompoundEditFieldLabel.Visible = 'off';
            app.MassofCompoundEditFieldLabel.Text = 'Mass of Compound';
            app.MassofCompoundEditField = uieditfield(app.LimitingReactantCalculatorTab, 'numeric');
            app.MassofCompoundEditField.Editable = 'off';
            app.MassofCompoundEditField.Enable = 'off';
            app.MassofCompoundEditField.Visible = 'off';
            app.CoefficientinreactionEditFieldLabel = uilabel(app.LimitingReactantCalculatorTab);
            app.CoefficientinreactionEditFieldLabel.Text = 'Coefficient in reaction';
            app.CoefficientinreactionEditField = uieditfield(app.LimitingReactantCalculatorTab, 'numeric');
            app.MolarMassEditFieldLabel = uilabel(app.LimitingReactantCalculatorTab);
            app.MolarMassEditFieldLabel.Enable = 'off';
            app.MolarMassEditFieldLabel.Visible = 'off';
            app.MolarMassEditFieldLabel.Text = 'Molar Mass';
            app.MolarMassEditField = uieditfield(app.LimitingReactantCalculatorTab, 'numeric');
            app.MolarMassEditField.Editable = 'off';
            app.MolarMassEditField.Enable = 'off';
            app.MolarMassEditField.Visible = 'off';
            app.EnterDataButton = uibutton(app.LimitingReactantCalculatorTab, 'push');
            app.EnterDataButton.ButtonPushedFcn = createCallbackFcn(app, @EnterDataButtonPushed, true);
            app.EnterDataButton.Text = 'Enter Data';
            app.LimitingReactantEditFieldLabel = uilabel(app.LimitingReactantCalculatorTab);
            app.LimitingReactantEditFieldLabel.Text = 'Limiting Reactant:';
            app.LimitingReactantEditField = uieditfield(app.LimitingReactantCalculatorTab, 'text');
            app.LimitingReactantEditField.Editable = 'off';
            app.LIMITINGREACTANTCALCULATORLabel = uilabel(app.LimitingReactantCalculatorTab);
            app.LIMITINGREACTANTCALCULATORLabel.Text = 'LIMITING REACTANT CALCULATOR';
            app.ResetButton = uibutton(app.LimitingReactantCalculatorTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.AppSummaryTab = uitab(app.TabGroup);
            app.AppSummaryTab.Title = 'App Summary ';
            app.Label = uilabel(app.AppSummaryTab);
            app.Label.Text = {'	The basic function of this app is to calculate the limiting reactant in a given reaction. The motivation behind this app is that finding the limiting reactant is a very useful skill required for Chemical Engineers as through the limiting reactant, there are multiple other process specifications that can be defined. The limiting reactant is also important to identify because not all substances in chemical reactions are obtained cheaply. Therefore, Chemical Engineers have to be able to identify the limiting reactant as this is often the reactant in the reaction that is less easily obtained in comparison to other compounds. Knowing the limiting reactant calculation can be important also to identify the yield of the products and compare it to the theoretical yield of the reaction. This calculation can show the efficiency of a certain process. Although the limiting reaction calculation may seem elementary, it is found to be quite useful for Chemical Engineers. '; '	                                                        	                                                        	                                                               '; '        In my Limiting Reactant Calculator App, I tried to make the app as user friendly as possible. The user is prompted to check the box that inquires if they are given masses or moles. If the user does not check this box, they are prompted to enter the moles of one reactant that is given and the coefficient of that reactant. They can enter this data into the table by clicking the “Enter Data” check box. The user can keep on entering as many reactants as they are given in the problem. If some of the data is given in masses, rather than moles, the user can click the “Entering Masses?” button. This will prompt the user to enter the mass, molar mass, and coefficient of that particular reactant. If a problem has data given in moles and masses, the user can switch between the entries they want to enter by clicking and unclicking the “Entering Masses?” check box. Once the user is done entering in all the data for the reactants in the problem, they can click the “Calculate” button to have the app determine the limiting reactant. The text at the bottom informs the user which reactant (in the order the user entered) is the limiting reactant(s) and the table in the app highlights this limiting reactant(s). Throughout the app, there are checks in the form of error statement that crashes the app if erroneous data is entered. None of the moles, coefficients, mass, or molar mass edit fields can accept a value less than or equal to zero because this is not possible in reality. If this happens, the user has to reset the app. The app also takes into account the fact that the reaction could be stochiometric and therefore all the reactants could identify as limiting. '; ''};
            app.AppSummaryandFeaturesLabel = uilabel(app.AppSummaryTab);
            app.AppSummaryandFeaturesLabel.Text = 'App Summary and Features';
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.UIFigure);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_App5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end