classdef isiddiq5_App6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PercentExcessAirCalculatorTab   matlab.ui.container.Tab
        OptimalPercentExcessGauge       matlab.ui.control.Gauge
        OptimalPercentExcessGaugeLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        PercentExcessAirEditField       matlab.ui.control.EditField
        PercentExcessAirEditFieldLabel  matlab.ui.control.Label
        PERCENTEXCESSAIRCALCULATORLabel  matlab.ui.control.Label
        MolarMassofHydrocarbonEditField  matlab.ui.control.NumericEditField
        MolarMassofHydrocarbonEditFieldLabel  matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        StochiometriccoefficientofoxygenEditField  matlab.ui.control.NumericEditField
        StochiometriccoefficientofoxygenEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        AirfeedintoreactorEditField     matlab.ui.control.NumericEditField
        AirfeedintoreactorEditFieldLabel  matlab.ui.control.Label
        HydrocarbonfeedintoreactorEditField  matlab.ui.control.NumericEditField
        HydrocarbonfeedintoreactorEditFieldLabel  matlab.ui.control.Label
        AppSummaryTab                   matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        AppSummaryandFeaturesLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.ResetButton.Visible = "on";
            HF = 0;
            Actual_Air = 0;
            if app.StochiometriccoefficientofoxygenEditField.Value <=0
                app.ResetButton.Visible = "on";
                error("The stochiometric coefficient cannot be 0 or less than 0")
            end
            if app.HydrocarbonfeedintoreactorEditField.Value <=0 || app.AirfeedintoreactorEditField.Value <= 0
                app.ResetButton.Visible = "on";
                error("The feed  cannot be less than or equal to 0")
            end
            if app.Switch.Value == "Mass"
                HF = app.HydrocarbonfeedintoreactorEditField.Value/app.MolarMassofHydrocarbonEditField.Value;
                Actual_Air = app.AirfeedintoreactorEditField.Value/28.79;
                if app.MolarMassofHydrocarbonEditField.Value <= 0
                    app.ResetButton.Visible = "on";
                    error("The molar mass cannot be less than or equal to 0")
                end
            else
                HF = app.HydrocarbonfeedintoreactorEditField.Value;
                Actual_Air = app.AirfeedintoreactorEditField.Value;
            end
            O = app.StochiometriccoefficientofoxygenEditField.Value;
            Theo_Air = HF*O/0.21;
            if Theo_Air > Actual_Air
                app.ResetButton.Visible = "on";
                error("The actual air feed cannot be less than the theoretical air fed in an excess!")
            end
            app.OptimalPercentExcessGauge.Value = 100*(Actual_Air - Theo_Air)/(Theo_Air);
            app.PercentExcessAirEditField.Value = 100*(Actual_Air - Theo_Air)/(Theo_Air) + "" + "%";
            PE = 100*(Actual_Air - Theo_Air)/(Theo_Air);
            if PE >= 10 && PE <= 20
                app.PercentExcessAirEditField.BackgroundColor = [0,1,0];
            end
            if PE > 20 && PE <= 40
                app.PercentExcessAirEditField.BackgroundColor = [1,0.5,0];
            end
            if PE > 40
                app.PercentExcessAirEditField.BackgroundColor = [1,0,0];
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Mass"
                app.MolarMassofHydrocarbonEditField.Enable = "on";
                app.MolarMassofHydrocarbonEditFieldLabel.Enable = "on";
                app.MolarMassofHydrocarbonEditField.Editable = "on";
                app.MolarMassofHydrocarbonEditFieldLabel.Visible = "on";
                app.MolarMassofHydrocarbonEditField.Visible = "on";
            else
                app.MolarMassofHydrocarbonEditField.Enable = "off";
                app.MolarMassofHydrocarbonEditFieldLabel.Enable = "off";
                app.MolarMassofHydrocarbonEditField.Editable = "off";
                app.MolarMassofHydrocarbonEditFieldLabel.Visible = "off";
                app.MolarMassofHydrocarbonEditField.Visible = "off";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            isiddiq5_App6();
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PercentExcessAirCalculatorTab = uitab(app.TabGroup);
            app.PercentExcessAirCalculatorTab.Title = 'Percent Excess Air Calculator';
            app.HydrocarbonfeedintoreactorEditFieldLabel = uilabel(app.PercentExcessAirCalculatorTab);
            app.HydrocarbonfeedintoreactorEditFieldLabel.Text = 'Hydrocarbon feed into reactor';
            app.HydrocarbonfeedintoreactorEditField = uieditfield(app.PercentExcessAirCalculatorTab, 'numeric');
            app.AirfeedintoreactorEditFieldLabel = uilabel(app.PercentExcessAirCalculatorTab);
            app.AirfeedintoreactorEditFieldLabel.Text = 'Air feed into reactor';
            app.AirfeedintoreactorEditField = uieditfield(app.PercentExcessAirCalculatorTab, 'numeric');
            app.CalculateButton = uibutton(app.PercentExcessAirCalculatorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.StochiometriccoefficientofoxygenEditFieldLabel = uilabel(app.PercentExcessAirCalculatorTab);
            app.StochiometriccoefficientofoxygenEditFieldLabel.Text = ' Stochiometric coefficient of oxygen';
            app.StochiometriccoefficientofoxygenEditField = uieditfield(app.PercentExcessAirCalculatorTab, 'numeric');
            app.Switch = uiswitch(app.PercentExcessAirCalculatorTab, 'slider');
            app.Switch.Items = {'Moles', 'Mass'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Moles';
            app.MolarMassofHydrocarbonEditFieldLabel = uilabel(app.PercentExcessAirCalculatorTab);
            app.MolarMassofHydrocarbonEditFieldLabel.Visible = 'off';
            app.MolarMassofHydrocarbonEditFieldLabel.Text = 'Molar Mass of Hydrocarbon';
            app.MolarMassofHydrocarbonEditField = uieditfield(app.PercentExcessAirCalculatorTab, 'numeric');
            app.MolarMassofHydrocarbonEditField.Editable = 'off';
            app.MolarMassofHydrocarbonEditField.Enable = 'off';
            app.MolarMassofHydrocarbonEditField.Visible = 'off';
            app.PERCENTEXCESSAIRCALCULATORLabel = uilabel(app.PercentExcessAirCalculatorTab);
            app.PERCENTEXCESSAIRCALCULATORLabel.Text = 'PERCENT EXCESS AIR CALCULATOR';
            app.PercentExcessAirEditFieldLabel = uilabel(app.PercentExcessAirCalculatorTab);
            app.PercentExcessAirEditFieldLabel.Text = 'Percent Excess Air';
            app.PercentExcessAirEditField = uieditfield(app.PercentExcessAirCalculatorTab, 'text');
            app.PercentExcessAirEditField.Editable = 'off';
            app.ResetButton = uibutton(app.PercentExcessAirCalculatorTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.OptimalPercentExcessGaugeLabel = uilabel(app.PercentExcessAirCalculatorTab);
            app.OptimalPercentExcessGaugeLabel.Text = 'Optimal Percent Excess';
            app.OptimalPercentExcessGauge = uigauge(app.PercentExcessAirCalculatorTab, 'circular');
            app.OptimalPercentExcessGauge.Limits = [0 200];
            app.AppSummaryTab = uitab(app.TabGroup);
            app.AppSummaryTab.Title = 'App Summary';
            app.Label = uilabel(app.AppSummaryTab);
            app.Label.Text = {'The basic function of this app is to calculate the percent excess air in a combustion process in which the user inputs the stochiometric coefficient of oxygen, and the feed rates of the hydrocarbon and air into the reactor. The motivation behind the app is that finding the percent excess of air is a very useful calculation for Chemical Engineers because it can help determine how much hydrocarbon is needed to keep the hydrocarbon as a limiting reactant. Chemical Engineers often encounter combustion processes because this process generates heat which can be used to power many products in the real world. Therefore, it is important for them to know how to handle percent excess calculations. '; '	'; '	In my Percent Excess Air Calculator app, I tried to make the app fulfill the most basic needs while accommodating for erroneous inputs. The app prompts the user to input the stochiometric coefficient of oxygen, and the feed rates of hydrocarbon and air into the reactor to determine the percent excess air. Because the feed rates can be entered in both mass and molar rates, there is a switch that allows the user to decide which type of inputs they are wanting to enter. If the user decides to choose mass flow rates, they will have to enter the molar mass of hydrocarbon as an additional input. When the user is done entering the input values, they can click the “Calculate” button and find the percent excess air in the text edit field below. If the user wishes to do another calculation afterwards, they can push the reset button that appears on the app. If the user enters values less than or equal to 0 for any of the inputs, the app gives an error and crashes because this is not a physically possible value for any of the inputs. Also, if the user enters inputs that yield the hydrocarbon as the excess reactant, the app gives an error and crashes because this will not only yield a negative percent excess air, but this condition is unfavorable in the real world because air is much more readily accessible than any hydrocarbon. When inputs that yield an inappropriate value, the reset button appears on the app and the user is required to push this button to restart their app. I also added a gauge that is based on the researched amount of excess air that is optimal for furnaces based on both the environmental and financial effects of using too much excess air (1). The gauge and the edit field showing the answer are color coded to show what percent air is favorable. '; ' '};
            app.AppSummaryandFeaturesLabel = uilabel(app.AppSummaryTab);
            app.AppSummaryandFeaturesLabel.Text = 'App Summary and Features';
            app.Label_2 = uilabel(app.AppSummaryTab);
            app.Label_2.Text = '(1) Axon, B. (2009, 08). Improve combustion system efficiency. Chemical Engineering Progress, 105, 41-46. Retrieved from http://search.proquest.com.ezproxy.lib.uh.edu/magazines/improve-combustion-system-efficiency/docview/221558200/se-2';
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.UIFigure);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_App6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end