classdef isiddiq5_App7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AntoineEquationCalculatorTab    matlab.ui.container.Tab
        ResetButton                     matlab.ui.control.Button
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        ConstantsLabel                  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ANTOINEEQUATIONCALCULATORLabel  matlab.ui.control.Label
        CalculateDropDown               matlab.ui.control.DropDown
        CalculateDropDownLabel          matlab.ui.control.Label
        TemperatureCelsiusEditField     matlab.ui.control.NumericEditField
        TemperatureCelsiusEditFieldLabel  matlab.ui.control.Label
        VaporPressuremmHgEditField      matlab.ui.control.NumericEditField
        VaporPressuremmHgEditFieldLabel  matlab.ui.control.Label
        CEditField                      matlab.ui.control.NumericEditField
        CEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        AEditField                      matlab.ui.control.NumericEditField
        AEditFieldLabel                 matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        AppSummaryandFeaturesTab        matlab.ui.container.Tab
        IbadSiddiqui2053648DrKowalLabel_2  matlab.ui.control.Label
        AppSummaryandFeaturesLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Choice = app.CalculateDropDown.Value;
            A = app.AEditField.Value;
            B = app.BEditField.Value;
            C = app.CEditField.Value;
            if A <=0 || B <= 0 || C <= 0
                error("the antoine constants cannot be less than or equal to 0")
            end
            switch Choice
                case "Vapor Pressure"
                    T = app.TemperatureCelsiusEditField.Value;
                    VaporPressure = 10^(A - B/(T+C));
                    app.VaporPressuremmHgEditField.Value = VaporPressure;
                case "Temperature"
                    V = app.VaporPressuremmHgEditField.Value;
                    Temperature = ((log10(V)-A)/(-B))^-1 - C;
                    app.TemperatureCelsiusEditField.Value = Temperature;
            end
        end

        % Drop down opening function: CalculateDropDown
        function CalculateDropDownOpening(app, event)

        end

        % Value changed function: CalculateDropDown
        function CalculateDropDownValueChanged(app, event)
            value = app.CalculateDropDown.Value;
            if value == "Vapor Pressure"
                app.VaporPressuremmHgEditField.Editable = "off";
                app.TemperatureCelsiusEditField.Editable = "on";
            else
                app.TemperatureCelsiusEditField.Editable = "off";
                app.VaporPressuremmHgEditField.Editable = "on";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            isiddiq5_App7();
            close(app.UIFigure)
        end

        % Callback function
        function MoleFractionCalculationCheckBoxValueChanged(app, event)

        end

        % Value changed function: TemperatureCelsiusEditField
        function TemperatureCelsiusEditFieldValueChanged(app, event)
            value = app.TemperatureCelsiusEditField.Value;
            if value < -273
                error("The temperature must be above absolute temperature")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AntoineEquationCalculatorTab = uitab(app.TabGroup);
            app.AntoineEquationCalculatorTab.Title = 'Antoine Equation Calculator';
            app.Label_4 = uilabel(app.AntoineEquationCalculatorTab);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.AntoineEquationCalculatorTab);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.AntoineEquationCalculatorTab);
            app.Label_2.Text = '';
            app.AEditFieldLabel = uilabel(app.AntoineEquationCalculatorTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.AntoineEquationCalculatorTab, 'numeric');
            app.AEditField.ValueDisplayFormat = '%.5f';
            app.BEditFieldLabel = uilabel(app.AntoineEquationCalculatorTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.AntoineEquationCalculatorTab, 'numeric');
            app.BEditField.ValueDisplayFormat = '%.5f';
            app.CEditFieldLabel = uilabel(app.AntoineEquationCalculatorTab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.AntoineEquationCalculatorTab, 'numeric');
            app.CEditField.ValueDisplayFormat = '%.5f';
            app.VaporPressuremmHgEditFieldLabel = uilabel(app.AntoineEquationCalculatorTab);
            app.VaporPressuremmHgEditFieldLabel.Text = 'Vapor Pressure (mmHg)';
            app.VaporPressuremmHgEditField = uieditfield(app.AntoineEquationCalculatorTab, 'numeric');
            app.VaporPressuremmHgEditField.ValueDisplayFormat = '%.3f';
            app.VaporPressuremmHgEditField.Editable = 'off';
            app.TemperatureCelsiusEditFieldLabel = uilabel(app.AntoineEquationCalculatorTab);
            app.TemperatureCelsiusEditFieldLabel.Text = 'Temperature (Celsius)';
            app.TemperatureCelsiusEditField = uieditfield(app.AntoineEquationCalculatorTab, 'numeric');
            app.TemperatureCelsiusEditField.ValueDisplayFormat = '%.3f';
            app.TemperatureCelsiusEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureCelsiusEditFieldValueChanged, true);
            app.CalculateDropDownLabel = uilabel(app.AntoineEquationCalculatorTab);
            app.CalculateDropDownLabel.Text = 'Calculate?';
            app.CalculateDropDown = uidropdown(app.AntoineEquationCalculatorTab);
            app.CalculateDropDown.Items = {'Vapor Pressure', 'Temperature'};
            app.CalculateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CalculateDropDownOpening, true);
            app.CalculateDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculateDropDownValueChanged, true);
            app.CalculateDropDown.Value = 'Vapor Pressure';
            app.ANTOINEEQUATIONCALCULATORLabel = uilabel(app.AntoineEquationCalculatorTab);
            app.ANTOINEEQUATIONCALCULATORLabel.Text = 'ANTOINE EQUATION CALCULATOR';
            app.CalculateButton = uibutton(app.AntoineEquationCalculatorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConstantsLabel = uilabel(app.AntoineEquationCalculatorTab);
            app.ConstantsLabel.Text = 'Constants';
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.AntoineEquationCalculatorTab);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            app.ResetButton = uibutton(app.AntoineEquationCalculatorTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AppSummaryandFeaturesTab = uitab(app.TabGroup);
            app.AppSummaryandFeaturesTab.Title = 'App Summary and Features';
            app.Label = uilabel(app.AppSummaryandFeaturesTab);
            app.Label.Text = {'	The basic function of the app is to calculate the vapor pressure or the temperature using the Antoine Equation. The motivation behind the app is that finding the vapor pressure or temperature of a specific system. This is important for Chemical Engineers because many systems that are used in the “real world” involve the changing of phases of a species into a different phase. The Antoine equation allows the engineer to know the temperature at which the stream is in saturated or more importantly the vapor pressure. If the engineer knows the total pressure of the system, they can easily calculate the mole fraction of the species knowing the vapor pressure. This calculation is very important because it can allow engineers to make adjustments in their streams based on the mole fractions of species in the output streams. '; '	In my Antoine Equation Calculator app, I tried to make the app as useful as possible in finding the necessary values from the Antoine Equation. The app prompts the user to enter the three constants required in the Antoine Equation (A, B, C). The app allows the user to input upto 5 decimal places for these constants because none of the tabulated constants have more than 5 decimal places. From there, the user can either decide if they want the app to calculate the vapor pressure or the temperature of the system using the “Calculate?” dropdown menu. Depending on what the user chooses, one of another numeric edit field becomes editable. If the user wants the app to determine the vapor pressure, they will have to enter the Temperature of the system in Celsius. If the user wants to determine the temperature of the system, they will have to enter the vapor pressure of the system in mmHg. The app does not include conversions from different units of vapor pressure or temperature into mmHg or Celsius respectively because the user can use other apps done previously to convert those values. The app will simply output the value that the user desires based on their selection in the dropdown menu after the calculate button has been clicked. To make this app function appropriately, there have been some checks that have been implemented. Firstly, none of the constants that the user enters can be below 0 because none of the tabulated Antoine constants are less than or equal to 0. If the user decides to enter a temperature, the temperature has to be above the absolute temperature (-273 Celsius) for the app to consider it an appropriate value. If the user enters an erroneous value, they have to click the reset button to enter in values that are appropriate for the situation.  '; ''};
            app.AppSummaryandFeaturesLabel = uilabel(app.AppSummaryandFeaturesTab);
            app.AppSummaryandFeaturesLabel.Text = 'App Summary and Features';
            app.IbadSiddiqui2053648DrKowalLabel_2 = uilabel(app.AppSummaryandFeaturesTab);
            app.IbadSiddiqui2053648DrKowalLabel_2.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_App7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end