classdef isiddiq5_App8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        RaoultsLawCalculatorAppTab     matlab.ui.container.Tab
        Label_13                       matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox                       matlab.ui.control.CheckBox
        SingleCondensableSpeciesCheckBox  matlab.ui.control.CheckBox
        PartialPressureofSpeciesEditField  matlab.ui.control.NumericEditField
        PartialPressureofSpeciesEditFieldLabel  matlab.ui.control.Label
        RaoultsLawCalculatorLabel      matlab.ui.control.Label
        MaximumVaporPressuremmHgEditField  matlab.ui.control.NumericEditField
        MaximumVaporPressuremmHgEditFieldLabel  matlab.ui.control.Label
        TotalPressuremmHgEditField     matlab.ui.control.NumericEditField
        TotalPressuremmHgEditFieldLabel  matlab.ui.control.Label
        MoleFractioninVaporEditField   matlab.ui.control.NumericEditField
        MoleFractioninVaporEditFieldLabel  matlab.ui.control.Label
        MoleFractioninLiquidEditField  matlab.ui.control.NumericEditField
        MoleFractioninLiquidEditFieldLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_14                       matlab.ui.control.Label
        Label_15                       matlab.ui.control.Label
        Label_16                       matlab.ui.control.Label
        Label_17                       matlab.ui.control.Label
        Label_18                       matlab.ui.control.Label
        Label_19                       matlab.ui.control.Label
        AppDescriptionandSummaryTab    matlab.ui.container.Tab
        IbadSiddiqui2053648DrKowalLabel_2  matlab.ui.control.Label
        AppSummaryandFeaturesLabel     matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        value1=0;
        value2=0;
        value3=0;
        value4=0;
        value5=0;
        value6=0;
        value7=0;
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function GivenDropDownValueChanged(app, event)

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            x = app.MoleFractioninLiquidEditField.Value;
            y = app.MoleFractioninVaporEditField.Value;
            Tp = app.TotalPressuremmHgEditField.Value;
            Vp = app.MaximumVaporPressuremmHgEditField.Value;
            Pp = app.PartialPressureofSpeciesEditField.Value;
            if app.CheckBox.Value == 1 && app.CheckBox_2.Value == 1
                Pp = x*Vp;
                app.PartialPressureofSpeciesEditField.Value = Pp;
                if app.CheckBox_3.Value == 1
                    y = Pp/Tp;
                    app.MoleFractioninVaporEditField.Value = y;
                end
                if app.CheckBox_4.Value == 1
                    Tp = Pp/y;
                    app.TotalPressuremmHgEditField.Value = Tp;
                end
            end
            if app.CheckBox_3.Value == 1 && app.CheckBox_4.Value == 1
                Pp = y*Tp;
                app.PartialPressureofSpeciesEditField.Value = Pp;
                if app.CheckBox.Value == 1
                    Vp = Pp/x;
                    app.MaximumVaporPressuremmHgEditField.Value = Vp;
                end
                if app.CheckBox_2.Value == 1
                    x = Pp/Vp;
                    app.MoleFractioninLiquidEditField.Value = x;
                end
            end
            if app.SingleCondensableSpeciesCheckBox.Value == 1
                if app.CheckBox_2.Value == 1
                    Pp = Vp;
                    app.PartialPressureofSpeciesEditField.Value = app.MaximumVaporPressuremmHgEditField.Value;
                end
                if app.CheckBox_5.Value == 1
                    Vp = Pp;
                    app.MaximumVaporPressuremmHgEditField.Value = app.PartialPressureofSpeciesEditField.Value;
                end
                if app.CheckBox_4.Value == 1 
                    Tp = Vp/y;
                    app.TotalPressuremmHgEditField.Value = Tp;
                end
                if app.CheckBox_3.Value == 1
                    y = Vp/Tp;
                    app.MoleFractioninVaporEditField.Value = y;
                end
            end
            if app.CheckBox_5.Value == 1
                if app.CheckBox_4.Value == 1
                    Tp = Pp/y;
                    app.TotalPressuremmHgEditField.Value = Tp;
                end
                if app.CheckBox_3.Value == 1
                    y = Pp/Tp;
                    app.MoleFractioninVaporEditField.Value = y;
                end
                if app.CheckBox.Value== 1
                    Vp = Pp/x;
                    app.MaximumVaporPressuremmHgEditField.Value = Vp;
                end
                if app.CheckBox_2.Value == 1 
                    x = Pp/Vp;
                    app.MoleFractioninLiquidEditField.Value = x;
                end
            end
            if app.MaximumVaporPressuremmHgEditField.Value ~= 0 && app.PartialPressureofSpeciesEditField.Value ~= 0
                if app.MaximumVaporPressuremmHgEditField.Value < app.PartialPressureofSpeciesEditField.Value  
                error("The partial pressure cannot be greater than the total pressure or maximum vapor pressure")
            end
            end
            if app.PartialPressureofSpeciesEditField.Value ~= 0 && app.TotalPressuremmHgEditField.Value ~= 0
                if app.PartialPressureofSpeciesEditField.Value > app.TotalPressuremmHgEditField.Value
                    error("The partial pressure cannot be greater than the total pressure or maximum vapor pressure")
                end
            end
        end

        % Value changed function: SingleCondensableSpeciesCheckBox
        function SingleCondensableSpeciesCheckBoxValueChanged(app, event)
            app.value7 = app.SingleCondensableSpeciesCheckBox.Value;
            if app.value7 == 1
                app.MoleFractioninLiquidEditField.Value = 1;
                app.MoleFractioninLiquidEditField.Enable = "off";
                app.CheckBox.Enable = "off";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            isiddiq5_App8();
            close(app.UIFigure)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            app.value1 = app.CheckBox.Value;
            if app.value1 == 1
                app.MoleFractioninLiquidEditField.Editable = "on";
            else
                app.MoleFractioninLiquidEditField.Editable = "off";
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            app.value2 = app.CheckBox_2.Value;
            if app.value2 == 1
                app.MaximumVaporPressuremmHgEditField.Editable = "on";
            else
                app.MaximumVaporPressuremmHgEditField.Editable = "off";
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            app.value3 = app.CheckBox_3.Value;
            if app.value3 == 1
                app.TotalPressuremmHgEditField.Editable = "on";
            else
                app.TotalPressuremmHgEditField.Editable = "off";
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            app.value4 = app.CheckBox_4.Value;
            if app.value4 == 1
                app.MoleFractioninVaporEditField.Editable = "on";
            else
                app.MoleFractioninVaporEditField.Editable = "off";
            end
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            app.value5 = app.CheckBox_5.Value;
            if app.value5 == 1
                app.PartialPressureofSpeciesEditField.Editable = "on";
            else
                app.PartialPressureofSpeciesEditField.Editable = "off";
            end
        end

        % Value changed function: MoleFractioninLiquidEditField
        function MoleFractioninLiquidEditFieldValueChanged(app, event)
            value = app.MoleFractioninLiquidEditField.Value;
            if value >1 || value <=0
                error("the mole fraction needs to be between 0 and 1")
            end
        end

        % Value changed function: MaximumVaporPressuremmHgEditField
        function MaximumVaporPressuremmHgEditFieldValueChanged(app, event)
            value = app.MaximumVaporPressuremmHgEditField.Value;
            if value <= 0 
                error("The max vapor pressure cannot be less than 0.")
            end
        end

        % Value changed function: TotalPressuremmHgEditField
        function TotalPressuremmHgEditFieldValueChanged(app, event)
            value = app.TotalPressuremmHgEditField.Value;
            if value <= 0 
                error("The total pressure cannot be less than 0.")
            end            
        end

        % Value changed function: MoleFractioninVaporEditField
        function MoleFractioninVaporEditFieldValueChanged(app, event)
            value = app.MoleFractioninVaporEditField.Value;
            if value >1 || value <=0
                error("the mole fraction needs to be between 0 and 1")
            end            
        end

        % Value changed function: PartialPressureofSpeciesEditField
        function PartialPressureofSpeciesEditFieldValueChanged(app, event)
            value = app.PartialPressureofSpeciesEditField.Value;
            if value <= 0 
                error("The partial pressure cannot be less than 0.")
            end             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RaoultsLawCalculatorAppTab = uitab(app.TabGroup);
            app.RaoultsLawCalculatorAppTab.Title = 'Raoult''s Law Calculator App';
            app.Label_19 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_19.Text = '';
            app.Label_18 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_18.Text = '';
            app.Label_17 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_17.Text = '';
            app.Label_16 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_16.Text = '';
            app.Label_15 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_15.Text = '';
            app.Label_14 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_14.Text = '';
            app.Label_10 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_10.Text = '';
            app.Label_9 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_9.Text = '';
            app.Label_8 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_8.Text = '';
            app.Label_7 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_7.Text = '';
            app.Label_6 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_6.Text = '';
            app.Label_5 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_5.Text = '';
            app.Label_4 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_2.Text = '';
            app.CalculateButton = uibutton(app.RaoultsLawCalculatorAppTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MoleFractioninLiquidEditFieldLabel = uilabel(app.RaoultsLawCalculatorAppTab);
            app.MoleFractioninLiquidEditFieldLabel.Text = 'Mole Fraction in Liquid';
            app.MoleFractioninLiquidEditField = uieditfield(app.RaoultsLawCalculatorAppTab, 'numeric');
            app.MoleFractioninLiquidEditField.ValueChangedFcn = createCallbackFcn(app, @MoleFractioninLiquidEditFieldValueChanged, true);
            app.MoleFractioninLiquidEditField.Editable = 'off';
            app.MoleFractioninVaporEditFieldLabel = uilabel(app.RaoultsLawCalculatorAppTab);
            app.MoleFractioninVaporEditFieldLabel.Text = 'Mole Fraction in Vapor';
            app.MoleFractioninVaporEditField = uieditfield(app.RaoultsLawCalculatorAppTab, 'numeric');
            app.MoleFractioninVaporEditField.ValueChangedFcn = createCallbackFcn(app, @MoleFractioninVaporEditFieldValueChanged, true);
            app.MoleFractioninVaporEditField.Editable = 'off';
            app.TotalPressuremmHgEditFieldLabel = uilabel(app.RaoultsLawCalculatorAppTab);
            app.TotalPressuremmHgEditFieldLabel.Text = 'Total Pressure (mmHg) ';
            app.TotalPressuremmHgEditField = uieditfield(app.RaoultsLawCalculatorAppTab, 'numeric');
            app.TotalPressuremmHgEditField.ValueChangedFcn = createCallbackFcn(app, @TotalPressuremmHgEditFieldValueChanged, true);
            app.TotalPressuremmHgEditField.Editable = 'off';
            app.MaximumVaporPressuremmHgEditFieldLabel = uilabel(app.RaoultsLawCalculatorAppTab);
            app.MaximumVaporPressuremmHgEditFieldLabel.Text = 'Maximum Vapor Pressure (mmHg)';
            app.MaximumVaporPressuremmHgEditField = uieditfield(app.RaoultsLawCalculatorAppTab, 'numeric');
            app.MaximumVaporPressuremmHgEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumVaporPressuremmHgEditFieldValueChanged, true);
            app.MaximumVaporPressuremmHgEditField.Editable = 'off';
            app.RaoultsLawCalculatorLabel = uilabel(app.RaoultsLawCalculatorAppTab);
            app.RaoultsLawCalculatorLabel.Text = 'Raoult''s Law Calculator';
            app.PartialPressureofSpeciesEditFieldLabel = uilabel(app.RaoultsLawCalculatorAppTab);
            app.PartialPressureofSpeciesEditFieldLabel.Text = 'Partial Pressure of Species';
            app.PartialPressureofSpeciesEditField = uieditfield(app.RaoultsLawCalculatorAppTab, 'numeric');
            app.PartialPressureofSpeciesEditField.ValueChangedFcn = createCallbackFcn(app, @PartialPressureofSpeciesEditFieldValueChanged, true);
            app.PartialPressureofSpeciesEditField.Editable = 'off';
            app.SingleCondensableSpeciesCheckBox = uicheckbox(app.RaoultsLawCalculatorAppTab);
            app.SingleCondensableSpeciesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleCondensableSpeciesCheckBoxValueChanged, true);
            app.SingleCondensableSpeciesCheckBox.Text = 'Single Condensable Species?';
            app.CheckBox = uicheckbox(app.RaoultsLawCalculatorAppTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.RaoultsLawCalculatorAppTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.RaoultsLawCalculatorAppTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.RaoultsLawCalculatorAppTab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.RaoultsLawCalculatorAppTab);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.ResetButton = uibutton(app.RaoultsLawCalculatorAppTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.RaoultsLawCalculatorAppTab);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            app.Label_11 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_12.Text = '';
            app.Label_13 = uilabel(app.RaoultsLawCalculatorAppTab);
            app.Label_13.Text = '';
            app.AppDescriptionandSummaryTab = uitab(app.TabGroup);
            app.AppDescriptionandSummaryTab.Title = 'App Description and Summary';
            app.Label = uilabel(app.AppDescriptionandSummaryTab);
            app.Label.Text = {'	The basic function of this app is to use the Raoult’s Law equation to calculate useful unknowns. The motivation behind the app is the effectiveness of Raoult’s Law in calculating various unknowns in a stream. Because many systems involve the condensing of a species into the liquid phase, the saturated vapor pressure is a powerful tool for Chemical Engineers in determining the characteristics of a stream. Using Raoult’s Law, the partial pressure, mole fraction in the liquid and vapor phase, total pressure, and vapor pressure of a system can be determined if the correct inputs are given. This information is important for Chemical Engineers to make sure the stream is functioning properly in the way they have designed it to. '; '	For my Raoult’s Law Calculator app, I spent the most amount of time trying to make it function so that it can calculate as many unknowns as possible by the given inputs. If the user inputs the mole fraction in liquid and the maximum vapor pressure, the app will be able to yield the partial pressure of the system. If the user enters these two inputs along with the mole fraction in the vapor phase or the total pressure, the app will be able to calculate the mole fraction in the vapor phase or the total pressure depending on which value is not given. Similarly, if the user enters the mole fraction in the vapor phase and the total pressure of the system, the app will be able to yield the partial pressure of that particular species. If the user enters the mole fraction in liquid or the maximum vapor pressure along with the two values entered before, the app will be able to calculate the mole fraction in liquid or the maximum vapor pressure depending on the input that is not provided. Additionally, if the user clicks the “Single Condensable Species” checkbox, the user the mole fraction in liquid edit field will automatically be set to 1 because the liquid contains a pure substance. From there, the user can enter the partial pressure of the species or the total pressure to yield the other value from the app. Once the user clicks the “Calculate” button, the app calculates all the appropriate values from the given number of inputs. There are a number of checks that are integrated in the app to make sure the user has entered appropriate values. Firstly, none of the mole fractions can be above 1 or below 0 because that is not physically reasonable. Also, the pressures entered in the inputs cannot be below 0 either. The partial pressure cannot be greater than the total pressure or the maximum vapor pressure of a species. If any of these unreasonable values are entered, then the app crashes and the user has to reset the app. '; ''};
            app.AppSummaryandFeaturesLabel = uilabel(app.AppDescriptionandSummaryTab);
            app.AppSummaryandFeaturesLabel.Text = 'App Summary and Features';
            app.IbadSiddiqui2053648DrKowalLabel_2 = uilabel(app.AppDescriptionandSummaryTab);
            app.IbadSiddiqui2053648DrKowalLabel_2.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_App8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end