classdef isiddiq5_app9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SensibleHeatChangesCalculatorTab  matlab.ui.container.Tab
        kJLabel                         matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        IbadSiddiqui2053648DrKowalLabel  matlab.ui.control.Label
        DeltaHEditField                 matlab.ui.control.NumericEditField
        DeltaHEditFieldLabel            matlab.ui.control.Label
        d1012EditField                  matlab.ui.control.NumericEditField
        d1012EditFieldLabel             matlab.ui.control.Label
        c108EditField                   matlab.ui.control.NumericEditField
        c108EditFieldLabel              matlab.ui.control.Label
        b105EditField                   matlab.ui.control.NumericEditField
        b105EditFieldLabel              matlab.ui.control.Label
        a103EditField                   matlab.ui.control.NumericEditField
        a103EditFieldLabel              matlab.ui.control.Label
        GoverningEquationDropDown       matlab.ui.control.DropDown
        GoverningEquationDropDownLabel  matlab.ui.control.Label
        FinalTemperatureEditField       matlab.ui.control.NumericEditField
        FinalTemperatureEditFieldLabel  matlab.ui.control.Label
        StartingTemperatureEditField    matlab.ui.control.NumericEditField
        StartingTemperatureEditFieldLabel  matlab.ui.control.Label
        SENSIBLEHEATCHANGESAPPLabel     matlab.ui.control.Label
        TemperaturesLabel               matlab.ui.control.Label
        ConstantsLabel                  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        AppSummaryandFeaturesTab        matlab.ui.container.Tab
        AppSummaryandFeaturesLabel      matlab.ui.control.Label
        IbadSiddiqui2053648DrKowalLabel_2  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            a = (app.a103EditField.Value) * 10^(-3);
            b = (app.b105EditField.Value) * 10^(-5);
            c = (app.c108EditField.Value) * 10^(-8);
            d = (app.d1012EditField.Value) * 10^(-12);
            T1 = app.StartingTemperatureEditField.Value;
            T2 = app.FinalTemperatureEditField.Value;
            switch app.GoverningEquationDropDown.Value
                case "1"
                    E = @(x) (a + b.*x + c.*x.^2 + d.*x.^3);
                    app.DeltaHEditField.Value = integral(@(x) E(x),T1,T2);
                case "2"
                    E = @(x) (a + b.*x + c.*x.^(-2));
                    app.DeltaHEditField.Value = integral(@(x) E(x),T1,T2);
            end
            if app.DeltaHEditField.Value > 0 
                app.Label.BackgroundColor = "#F94449";
            else
                app.Label.BackgroundColor = "#B1F2FF";
                
            end
        end

        % Value changed function: GoverningEquationDropDown
        function GoverningEquationDropDownValueChanged(app, event)
            value = app.GoverningEquationDropDown.Value;
            if value == "2"
                app.d1012EditFieldLabel.Visible = "off";
                app.d1012EditField.Visible = "off";
            else
                app.d1012EditFieldLabel.Visible = "on";
                app.d1012EditField.Visible = "on";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = "off";
            isiddiq5_app9();
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SensibleHeatChangesCalculatorTab = uitab(app.TabGroup);
            app.SensibleHeatChangesCalculatorTab.Title = 'Sensible Heat Changes Calculator';
            app.Label = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.Label.Text = '';
            app.CalculateButton = uibutton(app.SensibleHeatChangesCalculatorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConstantsLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.ConstantsLabel.Text = 'Constants';
            app.TemperaturesLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.TemperaturesLabel.Text = 'Temperatures';
            app.SENSIBLEHEATCHANGESAPPLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.SENSIBLEHEATCHANGESAPPLabel.Text = 'SENSIBLE HEAT CHANGES APP';
            app.StartingTemperatureEditFieldLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.StartingTemperatureEditFieldLabel.Text = 'Starting Temperature';
            app.StartingTemperatureEditField = uieditfield(app.SensibleHeatChangesCalculatorTab, 'numeric');
            app.StartingTemperatureEditField.Value = 25;
            app.FinalTemperatureEditFieldLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.FinalTemperatureEditFieldLabel.Text = 'Final Temperature';
            app.FinalTemperatureEditField = uieditfield(app.SensibleHeatChangesCalculatorTab, 'numeric');
            app.FinalTemperatureEditField.Value = 500;
            app.GoverningEquationDropDownLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.GoverningEquationDropDownLabel.Text = 'Governing Equation';
            app.GoverningEquationDropDown = uidropdown(app.SensibleHeatChangesCalculatorTab);
            app.GoverningEquationDropDown.Items = {'1', '2'};
            app.GoverningEquationDropDown.ValueChangedFcn = createCallbackFcn(app, @GoverningEquationDropDownValueChanged, true);
            app.GoverningEquationDropDown.Value = '1';
            app.a103EditFieldLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.a103EditFieldLabel.Text = 'a * 10^3';
            app.a103EditField = uieditfield(app.SensibleHeatChangesCalculatorTab, 'numeric');
            app.b105EditFieldLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.b105EditFieldLabel.Text = 'b * 10^5';
            app.b105EditField = uieditfield(app.SensibleHeatChangesCalculatorTab, 'numeric');
            app.c108EditFieldLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.c108EditFieldLabel.Text = 'c * 10^8';
            app.c108EditField = uieditfield(app.SensibleHeatChangesCalculatorTab, 'numeric');
            app.d1012EditFieldLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.d1012EditFieldLabel.Text = 'd * 10^12';
            app.d1012EditField = uieditfield(app.SensibleHeatChangesCalculatorTab, 'numeric');
            app.DeltaHEditFieldLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.DeltaHEditFieldLabel.Text = 'Delta H';
            app.DeltaHEditField = uieditfield(app.SensibleHeatChangesCalculatorTab, 'numeric');
            app.IbadSiddiqui2053648DrKowalLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.IbadSiddiqui2053648DrKowalLabel.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            app.ResetButton = uibutton(app.SensibleHeatChangesCalculatorTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.kJLabel = uilabel(app.SensibleHeatChangesCalculatorTab);
            app.kJLabel.Text = 'kJ';
            app.AppSummaryandFeaturesTab = uitab(app.TabGroup);
            app.AppSummaryandFeaturesTab.Title = 'App Summary and Features';
            app.Label_2 = uilabel(app.AppSummaryandFeaturesTab);
            app.Label_2.Text = {'The basic function of this app is to calculate the sensible heat changes for substances using either equation 1 or 2 in the table B.2 of the physical properties table. Because the app calculates the result of an integral, it is quite efficient for calculating the heat that is released or absorbed in a system. This calculation is very useful for Chemical Engineers because Chemical Engineers have to know if the process releases or absorbs heat. Moreover, they have to know how much heat is being absorbed or released because many reactions only occur at a certain temperature. Because in the real world, systems are not perfect, and therefore, some of the heat from the reactions in the system will not be conserved. To improve the efficiency of the system, the excess heat needs to be used in another part of the system. In essence, it is imperative that Chemical Engineers keep track of the heat exchanges in the system. '; '	For my Sensible Heat Changes Calculator App, I made the app as user friendly as possible. To work the app, the user has to enter the starting and ending temperatures of the system. Along with the temperatures, the user also has to enter the constants of the equation provided in table B.2. The user can choose to enter either Kelvin or Celsius values based on the requirements of the table. Because of this, there is no way to regulate if the user enters a value that is below the absolute temperature. The user also has to choose whether they want to use equation 1 or equation 2 from Table B.2 based on the phase of the substance. Equation 2 does not include the constant “d” and therefore when the user elects to use Equation 2 in the calculation the input requesting constant “d” disappears. Because the values in Table B.2 are given with values raised to a certain power, I have made the app account for that and thus the user will not have to do the conversions before entering the constants. Once the values have been entered, the user can click the “Calculate” button to enter calculate the integral to determine Delta H value for the values entered. Because in heat exchange problems, it is vital to track if the heat is being lost or gained in the reaction, I used the background of the app changes colors to signify this heat exchange. If heat is absorbed in the system (delta H) value is greater than 0, then the background turns light red, whereas if the delta H value is less than 0, the heat in the system is being released, and the app turns a light blue color. The user can click the reset button if incorrect values were entered or if they want to do another calculation. '; ''};
            app.IbadSiddiqui2053648DrKowalLabel_2 = uilabel(app.AppSummaryandFeaturesTab);
            app.IbadSiddiqui2053648DrKowalLabel_2.Text = 'Ibad Siddiqui 2053648 Dr. Kowal ';
            app.AppSummaryandFeaturesLabel = uilabel(app.AppSummaryandFeaturesTab);
            app.AppSummaryandFeaturesLabel.Text = 'App Summary and Features';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = isiddiq5_app9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end