classdef main3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        fxEditField                   matlab.ui.control.EditField
        fxEditFieldLabel              matlab.ui.control.Label
        DegreeofFunctionSpinner       matlab.ui.control.Spinner
        DegreeofFunctionSpinnerLabel  matlab.ui.control.Label
        FunctionTypeDropDown          matlab.ui.control.DropDown
        FunctionTypeDropDownLabel     matlab.ui.control.Label
        YTranslationEditField         matlab.ui.control.NumericEditField
        YTranslationEditFieldLabel    matlab.ui.control.Label
        XTranslationEditField         matlab.ui.control.NumericEditField
        XTranslationEditFieldLabel    matlab.ui.control.Label
        YMaxEditField                 matlab.ui.control.NumericEditField
        YMaxEditFieldLabel            matlab.ui.control.Label
        YMinEditField                 matlab.ui.control.NumericEditField
        YMinEditFieldLabel            matlab.ui.control.Label
        XMaxEditField                 matlab.ui.control.NumericEditField
        XMaxEditFieldLabel            matlab.ui.control.Label
        XMinEditField                 matlab.ui.control.NumericEditField
        XMinEditFieldLabel            matlab.ui.control.Label
        BaseGraphButton               matlab.ui.control.Button
        TransformedFunctionButton     matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        FunctionTransformerLabel      matlab.ui.control.Label
        IbadSiddiqui2053648FritzClaydonLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Horz = 0; % Horizontal Translation
        Vert = 0; % Vertical Translation
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.Vert = app.Vert + 1;
            app.YTranslationEditField.Value = app.Vert;
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.Vert = app.Vert - 1;
            app.YTranslationEditField.Value = app.Vert;
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.Horz = app.Horz - 1;
            app.XTranslationEditField.Value = app.Horz;
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.Horz = app.Horz + 1;
            app.XTranslationEditField.Value = app.Horz;
        end

        % Button pushed function: BaseGraphButton
        function BaseGraphButtonPushed(app, event)
            if(app.FunctionTypeDropDown.Value == "Linear" & app.DegreeofFunctionSpinner.Value > 1)
                error("A linear function can't be higher than degree 1.")
            end
            if(app.FunctionTypeDropDown.Value == "Polynomial" & app.DegreeofFunctionSpinner.Value == 1)
                error("A polynomial function cannot be of degree 1.")
            end
            min_x = app.XMinEditField.Value;
            max_x = app.XMaxEditField.Value;
            min_y = app.YMinEditField.Value;
            max_y = app.YMaxEditField.Value;
            if(app.FunctionTypeDropDown.Value == "Linear")
                y = [min_x:0.1:max_x];
                plot(app.UIAxes,[min_x:0.1:max_x],y,"b")
                hold(app.UIAxes,"on")
                app.UIAxes.XLim = [min_x,max_x];
                app.UIAxes.YLim = [min_y,max_y];
            end
            if(app.FunctionTypeDropDown.Value == "Polynomial") 
                x = [min_x:0.1:max_x];
                y = x.^(app.DegreeofFunctionSpinner.Value);
                plot(app.UIAxes,[min_x:0.1:max_x],y,"b")
                hold(app.UIAxes,"on")
                app.UIAxes.XLim = [min_x,max_x];
                app.UIAxes.YLim = [min_y,max_y];
            end
            
        end

        % Button pushed function: TransformedFunctionButton
        function TransformedFunctionButtonPushed(app, event)
            ytrans = app.YTranslationEditField.Value;
            xtrans = app.XTranslationEditField.Value;
            min_x = app.XMinEditField.Value;
            max_x = app.XMaxEditField.Value;
            min_y = app.YMinEditField.Value;
            max_y = app.YMaxEditField.Value;
            
            if(app.FunctionTypeDropDown.Value == "Linear")
                x = [min_x-xtrans:0.1:max_x-xtrans];
                y = [min_x-xtrans:0.1:max_x-xtrans];
                for i = 1:length(x)
                    x(i) = x(i) + xtrans;
                    y(i) = y(i) + ytrans
                end
                plot(app.UIAxes,x,y,"r")
                Coef = polyfit(x,y,1);
                Equation = "=";
                for i = 1:length(Coef)
                    if(i < length(Coef))
                        if Coef(i+1) >= 0
                            AddOn = sprintf("%0.2fx + ", Coef(i));
                            Equation = strcat(Equation,AddOn);
                        else
                            AddOn = sprintf("%0.2fx ", Coef(i));
                            Equation = strcat(Equation,AddOn);
                        end
                    else
                        AddOn = sprintf("%0.2f", Coef(i));
                        Equation = strcat(Equation,AddOn);
                    end
                    
                end
                app.fxEditField.Value = Equation;
            end
            if(app.FunctionTypeDropDown.Value == "Polynomial") 
                degree = app.DegreeofFunctionSpinner.Value;
                x = [min_x-xtrans:0.1:max_x+xtrans];
%               y = x.^(app.DegreeofFunctionSpinner.Value);
                for i = 1:length(x)
                    y(i) = x(i).^(app.DegreeofFunctionSpinner.Value) + ytrans;
                    x(i) = x(i) + xtrans;
                end
                plot(app.UIAxes,x,y,"r")
                Coef = polyfit(x,y,degree);
                Equation = "=";
                for i = 1:length(Coef)
                    if(i < length(Coef))
                        if Coef(i+1) >= 0
                            AddOn = sprintf("%0.2fx^%0g + ", Coef(i),degree+1-i);
                            Equation = strcat(Equation,AddOn);
                        else
                            AddOn = sprintf("%0.2fx^%0g ", Coef(i),degree+1-i);
                            Equation = strcat(Equation,AddOn);
                        end
                        else
                            AddOn = sprintf("%0.2f", Coef(i));
                            Equation = strcat(Equation,AddOn);
                    end
                    
                end
                app.fxEditField.Value = Equation;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Y vs X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IbadSiddiqui2053648FritzClaydonLabel = uilabel(app.UIFigure);
            app.IbadSiddiqui2053648FritzClaydonLabel.Text = 'Ibad Siddiqui 2053648 Fritz Claydon';
            app.FunctionTransformerLabel = uilabel(app.UIFigure);
            app.FunctionTransformerLabel.Text = 'Function Transformer';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.TransformedFunctionButton = uibutton(app.UIFigure, 'push');
            app.TransformedFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @TransformedFunctionButtonPushed, true);
            app.TransformedFunctionButton.Text = 'Transformed Function';
            app.BaseGraphButton = uibutton(app.UIFigure, 'push');
            app.BaseGraphButton.ButtonPushedFcn = createCallbackFcn(app, @BaseGraphButtonPushed, true);
            app.BaseGraphButton.Text = 'Base Graph';
            app.XMinEditFieldLabel = uilabel(app.UIFigure);
            app.XMinEditFieldLabel.Text = 'X Min';
            app.XMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.XMaxEditFieldLabel = uilabel(app.UIFigure);
            app.XMaxEditFieldLabel.Text = 'X Max';
            app.XMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YMinEditFieldLabel = uilabel(app.UIFigure);
            app.YMinEditFieldLabel.Text = 'Y Min';
            app.YMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.YMaxEditFieldLabel = uilabel(app.UIFigure);
            app.YMaxEditFieldLabel.Text = 'Y Max';
            app.YMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XTranslationEditFieldLabel = uilabel(app.UIFigure);
            app.XTranslationEditFieldLabel.Text = 'X Translation';
            app.XTranslationEditField = uieditfield(app.UIFigure, 'numeric');
            app.YTranslationEditFieldLabel = uilabel(app.UIFigure);
            app.YTranslationEditFieldLabel.Text = 'Y Translation';
            app.YTranslationEditField = uieditfield(app.UIFigure, 'numeric');
            app.FunctionTypeDropDownLabel = uilabel(app.UIFigure);
            app.FunctionTypeDropDownLabel.Text = 'Function Type';
            app.FunctionTypeDropDown = uidropdown(app.UIFigure);
            app.FunctionTypeDropDown.Items = {'Linear', 'Polynomial'};
            app.FunctionTypeDropDown.Value = 'Linear';
            app.DegreeofFunctionSpinnerLabel = uilabel(app.UIFigure);
            app.DegreeofFunctionSpinnerLabel.Text = 'Degree of Function';
            app.DegreeofFunctionSpinner = uispinner(app.UIFigure);
            app.DegreeofFunctionSpinner.Limits = [1 5];
            app.DegreeofFunctionSpinner.Value = 1;
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end