classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_25                        matlab.ui.control.Label
        TSHIRTPRODUCTIONCOSTCALCULATORLabel  matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        ISLabel                         matlab.ui.control.Label
        helpexchnagereturnsordertrackerLoginLabel_2  matlab.ui.control.Label
        helpexchnagereturnsordertrackerLoginLabel  matlab.ui.control.Label
        FEEDBACKLabel                   matlab.ui.control.Label
        SearchLabel                     matlab.ui.control.Label
        FREESTANDARDSHIPPINGRETURNSLabel  matlab.ui.control.Label
        KIDSLabel                       matlab.ui.control.Label
        WOMENLabel                      matlab.ui.control.Label
        MENLabel                        matlab.ui.control.Label
        ADDTOBAGLabel                   matlab.ui.control.Label
        BESTMACHINECHOICEEditField      matlab.ui.control.NumericEditField
        BESTMACHINECHOICEEditFieldLabel  matlab.ui.control.Label
        MINIMALTOTALCOSTEditField       matlab.ui.control.NumericEditField
        MINIMALTOTALCOSTEditFieldLabel  matlab.ui.control.Label
        CALCULATEButton                 matlab.ui.control.Button
        MACHINE3TOTALCOSTEditField      matlab.ui.control.NumericEditField
        MACHINE3TOTALCOSTLabel          matlab.ui.control.Label
        MACHINE2TOTALCOSTEditField      matlab.ui.control.NumericEditField
        MACHINE2TOTALCOSTEditFieldLabel  matlab.ui.control.Label
        MACHINE1TOTALCOSTEditField      matlab.ui.control.NumericEditField
        MACHINE1TOTALCOSTEditFieldLabel  matlab.ui.control.Label
        METALLICINKDropDown             matlab.ui.control.DropDown
        METALLICINKDropDownLabel        matlab.ui.control.Label
        NUMBEROFCOLORSINDESIGNEditField  matlab.ui.control.NumericEditField
        NUMBEROFCOLORSINDESIGNEditFieldLabel  matlab.ui.control.Label
        DESIGNWIDTHINCHESEditField      matlab.ui.control.NumericEditField
        DESIGNWIDTHINCHESEditFieldLabel  matlab.ui.control.Label
        DESIGNLENGTHINCHESEditField     matlab.ui.control.NumericEditField
        DESIGNLENGTHINCHESEditFieldLabel  matlab.ui.control.Label
        NUMBEROFSHIRTSORDEREDEditField  matlab.ui.control.NumericEditField
        NUMBEROFSHIRTSORDEREDEditFieldLabel  matlab.ui.control.Label
        IbadSiddiqui2053648DrClaydonLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            % inputs
            L = app.DESIGNLENGTHINCHESEditField.Value;
            W = app.DESIGNWIDTHINCHESEditField.Value;
            C = app.NUMBEROFCOLORSINDESIGNEditField.Value;
            A = L*W;
            M = app.METALLICINKDropDown.Value;
            X = app.NUMBEROFSHIRTSORDEREDEditField.Value;
            N = linspace(1,2.*X,2.*X);
            
            % Calculations
            switch M
                case 'YES'
                    Machine_1 = 10*W*C + 40 + 0.001*A*C*N + 2*N;
                    Machine_2 = 0.1*A*C + 20 + 0.02*A*C*N + 2*N;
                    Machine_3 = 15 + 0.12*A*N + 2*N;
                case 'NO'
                    Machine_1 = 10*W*C + 40 + 0.001*A*C*N;
                    Machine_2 = 0.1*A*C + 20 + 0.02*A*C*N;
                    Machine_3 = 15 + 0.12*A*N;
                 
            end
            % Optimization
            
            MachineChoices = [Machine_1(X),Machine_2(X),Machine_3(X)];
            [min_Cost,index] = min(MachineChoices);
            
            
            % outputs
            app.MACHINE1TOTALCOSTEditField.Value = Machine_1(X);
            app.MACHINE2TOTALCOSTEditField.Value = Machine_2(X);
            app.MACHINE3TOTALCOSTEditField.Value = Machine_3(X);
            app.MINIMALTOTALCOSTEditField.Value = min_Cost;
            app.BESTMACHINECHOICEEditField.Value = index;
            
            % plot
            M1_CostPerShirt = Machine_1./N;
            M2_CostPerShirt = Machine_2./N;
            M3_CostPerShirt = Machine_3./N;
            cla(app.UIAxes);
            plot(app.UIAxes,N,M1_CostPerShirt,'r');
            hold(app.UIAxes,"on");
            plot(app.UIAxes,N,M2_CostPerShirt,'g--');
            plot(app.UIAxes,N,M3_CostPerShirt,'b');
            plot(app.UIAxes,X,min_Cost/X,'cy*');
            xlim(app.UIAxes,[X/2,2.*X]);
            
            legend(app.UIAxes,'Machine 1','Machine 2','Machine 3','Best Option');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cost Per Shirt vs. Number of Shirts')
            xlabel(app.UIAxes, 'Number of Shirts ')
            ylabel(app.UIAxes, 'Cost Per Shirt')
            zlabel(app.UIAxes, 'Z')
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IbadSiddiqui2053648DrClaydonLabel = uilabel(app.UIFigure);
            app.IbadSiddiqui2053648DrClaydonLabel.Text = 'Ibad Siddiqui 2053648       Dr. Claydon';
            app.NUMBEROFSHIRTSORDEREDEditFieldLabel = uilabel(app.UIFigure);
            app.NUMBEROFSHIRTSORDEREDEditFieldLabel.Text = 'NUMBER OF SHIRTS ORDERED';
            app.NUMBEROFSHIRTSORDEREDEditField = uieditfield(app.UIFigure, 'numeric');
            app.DESIGNLENGTHINCHESEditFieldLabel = uilabel(app.UIFigure);
            app.DESIGNLENGTHINCHESEditFieldLabel.Text = 'DESIGN LENGTH (INCHES)';
            app.DESIGNLENGTHINCHESEditField = uieditfield(app.UIFigure, 'numeric');
            app.DESIGNWIDTHINCHESEditFieldLabel = uilabel(app.UIFigure);
            app.DESIGNWIDTHINCHESEditFieldLabel.Text = 'DESIGN WIDTH (INCHES)';
            app.DESIGNWIDTHINCHESEditField = uieditfield(app.UIFigure, 'numeric');
            app.NUMBEROFCOLORSINDESIGNEditFieldLabel = uilabel(app.UIFigure);
            app.NUMBEROFCOLORSINDESIGNEditFieldLabel.Text = 'NUMBER OF COLORS IN DESIGN';
            app.NUMBEROFCOLORSINDESIGNEditField = uieditfield(app.UIFigure, 'numeric');
            app.METALLICINKDropDownLabel = uilabel(app.UIFigure);
            app.METALLICINKDropDownLabel.Text = 'METALLIC INK';
            app.METALLICINKDropDown = uidropdown(app.UIFigure);
            app.METALLICINKDropDown.Items = {'YES', 'NO'};
            app.METALLICINKDropDown.Value = 'YES';
            app.MACHINE1TOTALCOSTEditFieldLabel = uilabel(app.UIFigure);
            app.MACHINE1TOTALCOSTEditFieldLabel.Text = 'MACHINE 1 TOTAL COST ';
            app.MACHINE1TOTALCOSTEditField = uieditfield(app.UIFigure, 'numeric');
            app.MACHINE1TOTALCOSTEditField.ValueDisplayFormat = '%.2f';
            app.MACHINE2TOTALCOSTEditFieldLabel = uilabel(app.UIFigure);
            app.MACHINE2TOTALCOSTEditFieldLabel.Text = 'MACHINE 2 TOTAL COST ';
            app.MACHINE2TOTALCOSTEditField = uieditfield(app.UIFigure, 'numeric');
            app.MACHINE2TOTALCOSTEditField.ValueDisplayFormat = '%.2f';
            app.MACHINE3TOTALCOSTLabel = uilabel(app.UIFigure);
            app.MACHINE3TOTALCOSTLabel.Text = 'MACHINE 3 TOTAL COST';
            app.MACHINE3TOTALCOSTEditField = uieditfield(app.UIFigure, 'numeric');
            app.MACHINE3TOTALCOSTEditField.ValueDisplayFormat = '%.2f';
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.MINIMALTOTALCOSTEditFieldLabel = uilabel(app.UIFigure);
            app.MINIMALTOTALCOSTEditFieldLabel.Text = 'MINIMAL TOTAL COST';
            app.MINIMALTOTALCOSTEditField = uieditfield(app.UIFigure, 'numeric');
            app.MINIMALTOTALCOSTEditField.ValueDisplayFormat = '%.2f';
            app.BESTMACHINECHOICEEditFieldLabel = uilabel(app.UIFigure);
            app.BESTMACHINECHOICEEditFieldLabel.Text = 'BEST MACHINE CHOICE';
            app.BESTMACHINECHOICEEditField = uieditfield(app.UIFigure, 'numeric');
            app.ADDTOBAGLabel = uilabel(app.UIFigure);
            app.ADDTOBAGLabel.Text = '   ADD TO BAG';
            app.MENLabel = uilabel(app.UIFigure);
            app.MENLabel.Text = 'MEN';
            app.WOMENLabel = uilabel(app.UIFigure);
            app.WOMENLabel.Text = 'WOMEN';
            app.KIDSLabel = uilabel(app.UIFigure);
            app.KIDSLabel.Text = 'KIDS';
            app.FREESTANDARDSHIPPINGRETURNSLabel = uilabel(app.UIFigure);
            app.FREESTANDARDSHIPPINGRETURNSLabel.Text = 'FREE STANDARD SHIPPING & RETURNS';
            app.SearchLabel = uilabel(app.UIFigure);
            app.SearchLabel.Text = 'Search ';
            app.FEEDBACKLabel = uilabel(app.UIFigure);
            app.FEEDBACKLabel.Text = 'FEEDBACK';
            app.helpexchnagereturnsordertrackerLoginLabel = uilabel(app.UIFigure);
            app.helpexchnagereturnsordertrackerLoginLabel.Text = 'help          exchnage & returns         order tracker         join creators club      creators club      Log in';
            app.helpexchnagereturnsordertrackerLoginLabel_2 = uilabel(app.UIFigure);
            app.helpexchnagereturnsordertrackerLoginLabel_2.Text = 'profile';
            app.ISLabel = uilabel(app.UIFigure);
            app.ISLabel.Text = 'I.S.';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '$';
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Text = '$';
            app.Label_23 = uilabel(app.UIFigure);
            app.Label_23.Text = '$';
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '$';
            app.TSHIRTPRODUCTIONCOSTCALCULATORLabel = uilabel(app.UIFigure);
            app.TSHIRTPRODUCTIONCOSTCALCULATORLabel.Text = 'T-SHIRT PRODUCTION COST CALCULATOR';
            app.Label_25 = uilabel(app.UIFigure);
            app.Label_25.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end