classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_19                       matlab.ui.control.Label
        Label_18                       matlab.ui.control.Label
        Label_17                       matlab.ui.control.Label
        Label_15                       matlab.ui.control.Label
        OUTPUTLabel                    matlab.ui.control.Label
        Label_13                       matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        GRAPHLabel                     matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        PROFILELabel                   matlab.ui.control.Label
        DONATETODAYLabel               matlab.ui.control.Label
        FUNDRAISERLabel                matlab.ui.control.Label
        BEACHRETREATLabel              matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        IbadSiddiqui2053648DrClaydonLabel  matlab.ui.control.Label
        CALCULATEButton                matlab.ui.control.Button
        PROFITPERSHIRTPSHIRTEditField  matlab.ui.control.NumericEditField
        PROFITPERSHIRTPSHIRTEditFieldLabel  matlab.ui.control.Label
        NUMBEROFSHIRTSSOLDNSHIRTSEditField  matlab.ui.control.NumericEditField
        NUMBEROFSHIRTSSOLDNSHIRTSEditFieldLabel  matlab.ui.control.Label
        MAXIMUMPROFITMEditField        matlab.ui.control.NumericEditField
        MAXIMUMPROFITMEditFieldLabel   matlab.ui.control.Label
        MINIMUMNUMBEROFTSHIRTSTOSELLSHIRTSEditField  matlab.ui.control.NumericEditField
        MINIMUMNUMBEROFTSHIRTSTOSELLSHIRTSEditFieldLabel  matlab.ui.control.Label
        PROFITPERSHIRTFORMINIMUMSALESSHIRTEditField  matlab.ui.control.NumericEditField
        PROFITPERSHIRTFORMINIMUMSALESSHIRTEditFieldLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_16                       matlab.ui.control.Label
        Label_14                       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function MinimumNumberofTShirtstoSellShirtsEditFieldValueChanged(app, event)


        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            %STEP 1 DEFINE THE PROBLEM
            
            % We are to maximize the total profit in the fundraiser given a
            % the minimum number of t-shirts sold and the profit per shirt
            % for the minimal sales.
            
            %STEP 2 COLLECT INFORMATION
            
            %Inputs
            
            MinShirt = app.MINIMUMNUMBEROFTSHIRTSTOSELLSHIRTSEditField.Value; 
            PPS_MinSales = app.PROFITPERSHIRTFORMINIMUMSALESSHIRTEditField.Value;
            
            %STEP 3 GENERATE A SOLUTION
            
            x = 0:1:20;
            NumberOfShirtsSold = MinShirt + 20.*x; %Equation I
            ProfitPerShirt = PPS_MinSales - (.5).*x; %Equation II
            TotalProfit = NumberOfShirtsSold.*ProfitPerShirt; %Equation III
            
            %STEP 4 REFINE AND IMPLEMENT
            
            [MaxProfit, index] = max(TotalProfit); %The Maximum Profit
            index
            %Outputs
            
            app.MAXIMUMPROFITMEditField.Value = MaxProfit;
            app.NUMBEROFSHIRTSSOLDNSHIRTSEditField.Value = NumberOfShirtsSold(index);
            app.PROFITPERSHIRTPSHIRTEditField.Value = ProfitPerShirt(index);
            
            %STEP 5 VERIFY AND TEST
            cla(app.UIAxes); %clearing the graphs
            plot(app.UIAxes,NumberOfShirtsSold,TotalProfit); 
            hold(app.UIAxes,'on'); % plotting more than one graph
            plot(app.UIAxes,NumberOfShirtsSold(index),MaxProfit,'md'); %Plotting the maximum point


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Total Profit vs Number of Shirts Sold')
            xlabel(app.UIAxes, 'Number of Shirts Sold (N) [Shirts]')
            ylabel(app.UIAxes, 'Total Profit [T] ($)')
            zlabel(app.UIAxes, 'Z')
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.PROFITPERSHIRTFORMINIMUMSALESSHIRTEditFieldLabel = uilabel(app.UIFigure);
            app.PROFITPERSHIRTFORMINIMUMSALESSHIRTEditFieldLabel.Text = 'PROFIT PER SHIRT FOR MINIMUM SALES [$/SHIRT]';
            app.PROFITPERSHIRTFORMINIMUMSALESSHIRTEditField = uieditfield(app.UIFigure, 'numeric');
            app.PROFITPERSHIRTFORMINIMUMSALESSHIRTEditField.ValueDisplayFormat = '%.2f';
            app.MINIMUMNUMBEROFTSHIRTSTOSELLSHIRTSEditFieldLabel = uilabel(app.UIFigure);
            app.MINIMUMNUMBEROFTSHIRTSTOSELLSHIRTSEditFieldLabel.Text = 'MINIMUM NUMBER OF T-SHIRTS TO SELL [SHIRTS]';
            app.MINIMUMNUMBEROFTSHIRTSTOSELLSHIRTSEditField = uieditfield(app.UIFigure, 'numeric');
            app.MAXIMUMPROFITMEditFieldLabel = uilabel(app.UIFigure);
            app.MAXIMUMPROFITMEditFieldLabel.Text = 'MAXIMUM PROFIT (M) [$]';
            app.MAXIMUMPROFITMEditField = uieditfield(app.UIFigure, 'numeric');
            app.MAXIMUMPROFITMEditField.ValueDisplayFormat = '%.2f';
            app.NUMBEROFSHIRTSSOLDNSHIRTSEditFieldLabel = uilabel(app.UIFigure);
            app.NUMBEROFSHIRTSSOLDNSHIRTSEditFieldLabel.Text = 'NUMBER OF SHIRTS SOLD (N) [SHIRTS]';
            app.NUMBEROFSHIRTSSOLDNSHIRTSEditField = uieditfield(app.UIFigure, 'numeric');
            app.NUMBEROFSHIRTSSOLDNSHIRTSEditField.ValueDisplayFormat = '%.2f';
            app.PROFITPERSHIRTPSHIRTEditFieldLabel = uilabel(app.UIFigure);
            app.PROFITPERSHIRTPSHIRTEditFieldLabel.Text = 'PROFIT PER SHIRT (P) [$/SHIRT]';
            app.PROFITPERSHIRTPSHIRTEditField = uieditfield(app.UIFigure, 'numeric');
            app.PROFITPERSHIRTPSHIRTEditField.ValueDisplayFormat = '%.2f';
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.IbadSiddiqui2053648DrClaydonLabel = uilabel(app.UIFigure);
            app.IbadSiddiqui2053648DrClaydonLabel.Text = 'Ibad Siddiqui 2053648 Dr. Claydon    ';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.BEACHRETREATLabel = uilabel(app.UIFigure);
            app.BEACHRETREATLabel.Text = 'BEACH RETREAT';
            app.FUNDRAISERLabel = uilabel(app.UIFigure);
            app.FUNDRAISERLabel.Text = 'FUNDRAISER';
            app.DONATETODAYLabel = uilabel(app.UIFigure);
            app.DONATETODAYLabel.Text = 'DONATE TODAY';
            app.PROFILELabel = uilabel(app.UIFigure);
            app.PROFILELabel.Text = 'PROFILE';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.GRAPHLabel = uilabel(app.UIFigure);
            app.GRAPHLabel.Text = 'GRAPH';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '$';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '$';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end