classdef appS5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MENULabel                  matlab.ui.control.Label
        Label_28                   matlab.ui.control.Label
        Label_27                   matlab.ui.control.Label
        Label_26                   matlab.ui.control.Label
        Label_25                   matlab.ui.control.Label
        Label_24                   matlab.ui.control.Label
        Label_23                   matlab.ui.control.Label
        Label_22                   matlab.ui.control.Label
        Label_21                   matlab.ui.control.Label
        Label_20                   matlab.ui.control.Label
        Label_19                   matlab.ui.control.Label
        Label_18                   matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
        Label_15                   matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        PCAKLabel                  matlab.ui.control.Label
        SAEMSLabel                 matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        AddImageButton             matlab.ui.control.Button
        ChooseImageDropDown        matlab.ui.control.DropDown
        ChooseImageDropDownLabel   matlab.ui.control.Label
        IbadSiddiqui2053648Label   matlab.ui.control.Label
        LoadBackgroundImageButton  matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        Label_29                   matlab.ui.control.Label
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
         % The background of the app
        Background = imread("background.jpg");
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadBackgroundImageButton
        function LoadBackgroundImageButtonPushed(app, event)
            %Task 5.2 - Add Background Image
            app.Image.ImageSource = app.Background;
            
        end

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            %Task 5.1 - Loading Images
            an_image = imread("astronaut.jpg");
            dog_image = imread("dog.jpg");
            cat_image = imread("cat.jpg");
            ufo_image = imread("ufo.jpg");
            dv_image = imread("darthvader.jpg");
            %Astronaut Layer Separation
            an_imageR = an_image(:,:,1);
            an_imageG = an_image(:,:,2);
            an_imageB = an_image(:,:,3);
            %Dog Layer Separation
            dog_imageR = dog_image(:,:,1);
            dog_imageG = dog_image(:,:,2);
            dog_imageB = dog_image(:,:,3);
            %Cat Layer Separation
            cat_imageR = cat_image(:,:,1);
            cat_imageG = cat_image(:,:,2);
            cat_imageB = cat_image(:,:,3);
            %DarthVader Layer Separation
            dv_imageR = dv_image(:,:,1);
            dv_imageG = dv_image(:,:,2);
            dv_imageB = dv_image(:,:,3);
            %UFO Layer Separation
            ufo_imageR = ufo_image(:,:,1);
            ufo_imageG = ufo_image(:,:,2);
            ufo_imageB = ufo_image(:,:,3);
            
            % Task 5.3 - Add Images to Background
            
            Choose = app.ChooseImageDropDown.Value;
            
            switch Choose
                case 'UFO'
                    UFOMask = ufo_imageR<30 & ufo_imageG >245 & ufo_imageB < 30;
                    CatUFOMask = cat(3,UFOMask,UFOMask,UFOMask);
                    ufo_image(CatUFOMask) = app.Background(CatUFOMask);
                    app.Background = ufo_image; %So that all the images appear together
                    app.Image.ImageSource = ufo_image; 
                case 'Dog'
                    DogMask = dog_imageR<50 & dog_imageG >245 & dog_imageB < 60;
                    CatDogMask = cat(3,DogMask,DogMask,DogMask);
                    dog_image(CatDogMask) = app.Background(CatDogMask);
                    app.Background = dog_image; %So that all the images appear together
                    app.Image.ImageSource = dog_image;
                case 'Cat'
                    CatMask = cat_imageR<50 & cat_imageG >250 & cat_imageB < 50;
                    CatCatMask = cat(3,CatMask,CatMask,CatMask);
                    cat_image(CatCatMask) = app.Background(CatCatMask);
                    app.Background = cat_image; %So that all the images appear together
                    app.Image.ImageSource = cat_image;
                case 'Darth Vader'
                    DVMask = dv_imageR< 50 & dv_imageG >245 & dv_imageB < 40;
                    CatDVMask = cat(3,DVMask,DVMask,DVMask);
                    dv_image(CatDVMask) = app.Background(CatDVMask);
                    app.Background = dv_image; %So that all the images appear together
                    app.Image.ImageSource = dv_image;
                case 'Astronaut'
                    ANMask = an_imageR<90 & an_imageG >245 & an_imageB < 20;
                    CatANMask = cat(3,ANMask,ANMask,ANMask);
                    an_image(CatANMask) = app.Background(CatANMask);
                    app.Background = an_image; %So that all the images appear together
                    app.Image.ImageSource = an_image;
            
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_29 = uilabel(app.UIFigure);
            app.Label_29.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.LoadBackgroundImageButton = uibutton(app.UIFigure, 'push');
            app.LoadBackgroundImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBackgroundImageButtonPushed, true);
            app.LoadBackgroundImageButton.Text = 'Load Background Image';
            app.IbadSiddiqui2053648Label = uilabel(app.UIFigure);
            app.IbadSiddiqui2053648Label.Text = 'Ibad Siddiqui 2053648 ';
            app.ChooseImageDropDownLabel = uilabel(app.UIFigure);
            app.ChooseImageDropDownLabel.Text = 'Choose Image';
            app.ChooseImageDropDown = uidropdown(app.UIFigure);
            app.ChooseImageDropDown.Items = {'Astronaut', 'Cat', 'Dog', 'Darth Vader', 'UFO'};
            app.ChooseImageDropDown.Value = 'Astronaut';
            app.AddImageButton = uibutton(app.UIFigure, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Text = 'Add Image';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.SAEMSLabel = uilabel(app.UIFigure);
            app.SAEMSLabel.Text = 'S    A    E  M    S ';
            app.PCAKLabel = uilabel(app.UIFigure);
            app.PCAKLabel.Text = 'P   C         A  K';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '*';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '*';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '*';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '*';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '*';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '*';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '*';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '*';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '*';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '*';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '*';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '*';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '*';
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Text = '*';
            app.Label_23 = uilabel(app.UIFigure);
            app.Label_23.Text = '*';
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '*';
            app.Label_25 = uilabel(app.UIFigure);
            app.Label_25.Text = '*';
            app.Label_26 = uilabel(app.UIFigure);
            app.Label_26.Text = '*';
            app.Label_27 = uilabel(app.UIFigure);
            app.Label_27.Text = '*';
            app.Label_28 = uilabel(app.UIFigure);
            app.Label_28.Text = '*';
            app.MENULabel = uilabel(app.UIFigure);
            app.MENULabel.Text = 'MENU';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appS5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end